classdef FilePreviewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        YPositionSlider             matlab.ui.control.Slider
        YPositionSliderLabel        matlab.ui.control.Label
        XPositionSlider             matlab.ui.control.Slider
        XPositionSliderLabel        matlab.ui.control.Label
        ofChannelsSwitch            matlab.ui.control.Switch
        ofChannelsSwitchLabel       matlab.ui.control.Label
        UpdatePlotsButton           matlab.ui.control.Button
        ROISizeSlider               matlab.ui.control.Slider
        ROISizeSliderLabel          matlab.ui.control.Label
        ClearDataButton             matlab.ui.control.Button
        Ch2Lamp                     matlab.ui.control.Lamp
        Ch1Lamp                     matlab.ui.control.Lamp
        ChooseCh2Button             matlab.ui.control.Button
        ChooseCh1Button             matlab.ui.control.Button
        SpeedEditField              matlab.ui.control.NumericEditField
        SpeedSlider                 matlab.ui.control.Slider
        SpeedSliderLabel            matlab.ui.control.Label
        FrameSelectionCheckBox      matlab.ui.control.CheckBox
        EndFrameEditField           matlab.ui.control.NumericEditField
        EndFrameEditFieldLabel      matlab.ui.control.Label
        StartFrameEditField         matlab.ui.control.NumericEditField
        StartFrameEditFieldLabel    matlab.ui.control.Label
        LoopContinuousCheckBox      matlab.ui.control.CheckBox
        RunButton                   matlab.ui.control.Button
        ColormapOptionsButtonGroup  matlab.ui.container.ButtonGroup
        HotButton                   matlab.ui.control.RadioButton
        JetButton                   matlab.ui.control.RadioButton
        CoolButton                  matlab.ui.control.RadioButton
        HSVButton                   matlab.ui.control.RadioButton
        TurboButton                 matlab.ui.control.RadioButton
        ParulaButton                matlab.ui.control.RadioButton
        ofColorsSlider              matlab.ui.control.Slider
        ofColorsSliderLabel         matlab.ui.control.Label
        ColormapLabel               matlab.ui.control.Label
        Switch                      matlab.ui.control.Switch
        FramesEditField             matlab.ui.control.NumericEditField
        FramesSlider                matlab.ui.control.Slider
        FramesSliderLabel           matlab.ui.control.Label
        SelectDataButton            matlab.ui.control.Button
        Ch2ROIUIAxes                matlab.ui.control.UIAxes
        Ch2UIAxes                   matlab.ui.control.UIAxes
        Ch1ROIUIAxes                matlab.ui.control.UIAxes
        Ch1UIAxes                   matlab.ui.control.UIAxes
        DataUIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        CallingApp; % Parent app
        filePath;
        %Stack; % Image stack
        Ch1Stack; % Channel 1 Image stack
        Ch2Stack; % Channel 2 Image stack
        isColormapOn;
        height = 100;
        width = 1000;
        isFrameSelectionEnabled;
        stackSpeed;
        ROIsquare;
        currRectangle;
        currentROIcoors;
        CurrentPosX = 10;
        CurrentPosY = 10;
    end
    
    methods (Access = public)
        
        % Calulates the colormapping for the given frame & value
        function file = colorMapChange(app, frame, val)
            if (app.HotButton.Value)
                file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,frame))), hot(val));
            elseif (app.JetButton.Value)
                file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,frame))), jet(val));
            elseif (app.CoolButton.Value)
                file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,frame))), cool(val));
            elseif (app.HSVButton.Value)
                file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,frame))), hsv(val));
            elseif (app.TurboButton.Value)
                file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,frame))), turbo(val));
            else
                file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,frame))), parula(val));
            end
        end
        
        function check = checkContinuous(app)
            if (app.LoopContinuousCheckBox.Value)
                check = 1;
            else
                check = 0;
            end
        end
        
        function showFrames(app, startFrame, endFrame)
            for i = startFrame:endFrame
                % Check if colormap on
                if (strcmp(app.isColormapOn, 'On'))
                    file = colorMapChange(app, i, ceil(app.ofColorsSlider.Value));
                % Colormap off
                else
                    file = app.Stack(:,:,i);
                end
                            
                app.FramesSlider.Value = i;
                app.FramesEditField.Value = i;
                            
                imshow(file, [100, 900], 'Parent', app.DataUIAxes);
                            
                pause(1./(app.stackSpeed * 10));              
            end
        end
        
        function enableSliders(app)
            app.FramesSlider.Enable = 'On';
            app.FramesSliderLabel.Enable = 'On';
            app.FramesEditField.Enable = 'On';
            app.ofColorsSlider.Enable = 'On';
            app.ofColorsSliderLabel.Enable = 'On';
            app.Switch.Enable = 'On';
            app.ColormapOptionsButtonGroup.Enable = 'On';
            app.StartFrameEditField.Enable = 'On';
            app.StartFrameEditFieldLabel.Enable = 'On';
            app.EndFrameEditField.Enable = 'On';
            app.EndFrameEditFieldLabel.Enable = 'On';
            app.FrameSelectionCheckBox.Enable = 'On';
            app.SpeedSlider.Enable = 'On';
            app.SpeedSliderLabel.Enable = 'On';
            app.SpeedEditField.Enable = 'On';
            app.LoopContinuousCheckBox.Enable = 'On';
            app.RunButton.Enable = 'On';
            app.ROISizeSlider.Enable = 'On';
            app.ROISizeSliderLabel.Enable = 'On';
            app.XPositionSlider.Enable = 'On';
            app.XPositionSliderLabel.Enable = 'On';
            app.YPositionSlider.Enable = 'On';
            app.YPositionSliderLabel.Enable = 'On';
            
            disableDefaultInteractivity(app.Ch1UIAxes);
            disableDefaultInteractivity(app.Ch1ROIUIAxes);
            disableDefaultInteractivity(app.Ch2UIAxes);
            disableDefaultInteractivity(app.Ch2ROIUIAxes);
        end
        
        function disableSliders(app)
            app.FramesSlider.Enable = 'Off';
            app.FramesSliderLabel.Enable = 'Off';
            app.FramesEditField.Enable = 'Off';
            app.ofColorsSlider.Enable = 'Off';
            app.ofColorsSliderLabel.Enable = 'Off';
            app.Switch.Enable = 'Off';
            app.ColormapOptionsButtonGroup.Enable = 'Off';
            app.StartFrameEditField.Enable = 'Off';
            app.StartFrameEditFieldLabel.Enable = 'Off';
            app.EndFrameEditField.Enable = 'Off';
            app.EndFrameEditFieldLabel.Enable = 'Off';
            app.FrameSelectionCheckBox.Enable = 'Off';
            app.SpeedSlider.Enable = 'Off';
            app.SpeedSliderLabel.Enable = 'Off';
            app.SpeedEditField.Enable = 'Off';
            app.LoopContinuousCheckBox.Enable = 'Off';
            app.RunButton.Enable = 'Off';
            app.ROISizeSlider.Enable = 'Off';
            app.ROISizeSliderLabel.Enable = 'Off';
            app.XPositionSlider.Enable = 'Off';
            app.XPositionSliderLabel.Enable = 'Off';
            app.YPositionSlider.Enable = 'Off';
            app.YPositionSliderLabel.Enable = 'Off';
            
            disableDefaultInteractivity(app.Ch1UIAxes);
            disableDefaultInteractivity(app.Ch1ROIUIAxes);
            disableDefaultInteractivity(app.Ch2UIAxes);
            disableDefaultInteractivity(app.Ch2ROIUIAxes);
        end
        
        function deleteImage(~, axes)
            Image = findobj(axes, 'Type', 'image');
            
            if (~isempty(Image))
                cla(axes, 'reset');
            end
        end
        
        function resetTicks(~, axes)
            set(axes,'xtick',[])
            set(axes,'xticklabel',[])
            set(axes,'ytick',[])
            set(axes,'yticklabel',[])
        end
        
        function resetAxes(app)
            axis(app.Ch1UIAxes, 'equal');
            axis(app.Ch1ROIUIAxes, 'equal');
            axis(app.Ch2UIAxes, 'equal');
            axis(app.Ch2ROIUIAxes, 'equal');
            app.Ch1UIAxes.XColor = 'w';
            app.Ch1UIAxes.YColor = 'w';
            app.Ch1ROIUIAxes.XColor = 'w';
            app.Ch1ROIUIAxes.YColor = 'w';
            app.Ch2UIAxes.XColor = 'w';
            app.Ch2UIAxes.YColor = 'w';
            app.Ch2ROIUIAxes.XColor = 'w';
            app.Ch2ROIUIAxes.YColor = 'w';
            app.Ch1UIAxes.Toolbar.Visible = 'Off';
            app.Ch1ROIUIAxes.Toolbar.Visible = 'Off';
            app.Ch2UIAxes.Toolbar.Visible = 'Off';
            app.Ch2ROIUIAxes.Toolbar.Visible = 'Off';
            
            resetTicks(app, app.Ch1UIAxes);
            resetTicks(app, app.Ch1ROIUIAxes);
            resetTicks(app, app.Ch2UIAxes);
            resetTicks(app, app.Ch2ROIUIAxes);
            
            disableDefaultInteractivity(app.Ch1UIAxes);
            disableDefaultInteractivity(app.Ch1ROIUIAxes);
            disableDefaultInteractivity(app.Ch2UIAxes);
            disableDefaultInteractivity(app.Ch2ROIUIAxes);
        end
        
        %{
        function ROIChange(app, evt, roi)
            event = evt.EventName;
            
            imshow('Parent', app.Ch1ROIUIAxes);
            
            switch(event)
                case{'MovingROI'}
                    disp('ROI moving');
                case{'ROIMoved'}
                    disp('ROI Moved');
            end
        end
        %}
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, STICSgui1)
            app.CallingApp = STICSgui1;
            axis(app.DataUIAxes, 'equal');
            %app.DataUIAxes.XColor = 'w';
            %app.DataUIAxes.YColor = 'w';
            
            resetAxes(app);
            
            app.isColormapOn = app.Switch.Value;
            %disableDefaultInteractivity(app.DataUIAxes);
            app.stackSpeed = app.SpeedSlider.Value;
        end

        % Button pushed function: SelectDataButton
        function SelectDataButtonPushed(app, event)
            %{
            [fileName, pathName] = uigetfile('*.tif','Please select your data set');
            
            % Check if user clicked cancel
            if (fileName == 0)
                return
            end
            
            app.filePath = pathName;
            ch1name=fileName(1:end-4);
            data = [app.filePath ch1name '.tif'];
            
            % Add path to code from parent folder
            addpath('current/STICS/');
            
            % Check if data is in MultiTiff files
            if (strcmp(fileName(end-2:end),'tif'))
                try
                    app.Stack = readFileToStack(data);
                catch Error
                    % Select another file
                    [app.StackFileName, app.StackPath] = uigetfile('*.tif', 'Select Stack File');
                    app.Stack = readFileToStack([app.StackPath, app.StackFileName]);
                end
                
                isValid = 1;
            else
                isValid = 0;
            end
            
            app.FramesSlider.Limits = [1, size(app.Stack, 3)];
            
            %app.ofColorsSlider.Limits = [1, max(app.Stack(:,:,1), [], 'all')];
            app.ofColorsSlider.Limits = [1, 255];
            
            % Convert image to uint8
            %index = 1:size(app.Stack, 3);
            %app.Stack(:,:,index) = im2uint8(app.Stack(:,:,index));
            
            %app.Stack(:,:,1)
            
            %uint8(255 * mat2gray(app.Stack(:,:,1)))
            %rescale(app.Stack(:,:,ceil(app.FramesSlider.Value)))
            %ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,1))), jet(app.ofColorsSlider.Value))
            
            %im2uint8(app.Stack(:,:,1))
            
            % Set image as first frame once file loaded
            file = app.Stack(:,:,1);
            
            %{
            if (strcmp(app.isColormapOn, 'Off'))
                file = app.Stack(:,:,1);
                %file = uint8(255 * mat2gray(app.Stack(:,:,1)));
            else
                %file = ind2rgb(app.Stack(:,:,1), jet(app.ofColorsSlider.Value));
                %file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,1))), jet(app.ofColorsSlider.Value));
                %file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,1))), cool(app.ofColorsSlider.Value));
                %file = ind2rgb(rescale(app.Stack(:,:,1)), jet(app.ofColorsSlider.Value));
                %file = imagesc(rescale(app.Stack(:,:,ceil(app.FramesSlider.Value))));
                %file = ind2rgb(app.Stack(:,:,1), cool(app.ofColorsSlider.Value));
            end
            %}
            
            
            imshow(file, [app.height, app.width], 'Parent', app.DataUIAxes);
            
            % Check if file valid
            if (isValid)
                % Enable adjustable fields
                app.SelectDataButton.BackgroundColor = [0.33 0.77 0.01];
                app.FramesSlider.Enable = 'On';
                app.FramesSliderLabel.Enable = 'On';
                app.FramesEditField.Enable = 'On';
                app.ofColorsSlider.Enable = 'On';
                app.ofColorsSliderLabel.Enable = 'On';
                app.Switch.Enable = 'On';
                app.ColormapOptionsButtonGroup.Enable = 'On';
                %app.StartFrameEditField.Enable = 'On';
                %app.StartFrameEditFieldLabel.Enable = 'On';
                %app.EndFrameEditField.Enable = 'On';
                %app.EndFrameEditFieldLabel.Enable = 'On';
                app.FrameSelectionCheckBox.Enable = 'On';
                app.SpeedSlider.Enable = 'On';
                app.SpeedSliderLabel.Enable = 'On';
                app.SpeedEditField.Enable = 'On';
                app.LoopContinuousCheckBox.Enable = 'On';
                app.RunButton.Enable = 'On';
                
            % File invalid
            else
                % Disable adjustable fields
                app.SelectDataButton.BackgroundColor = [1.0 0.0 0.0];
                app.FramesSlider.Enable = 'Off';
                app.FramesSliderLabel.Enable = 'Off';
                app.FramesEditField.Enable = 'Off';
                app.ofColorsSlider.Enable = 'Off';
                app.ofColorsSliderLabel.Enable = 'Off';
                app.Switch.Enable = 'Off';
                app.ColormapOptionsButtonGroup.Enable = 'Off';
                app.StartFrameEditField.Enable = 'Off';
                app.StartFrameEditFieldLabel.Enable = 'Off';
                app.EndFrameEditField.Enable = 'Off';
                app.EndFrameEditFieldLabel.Enable = 'Off';
                app.FrameSelectionCheckBox.Enable = 'Off';
                app.SpeedSlider.Enable = 'Off';
                app.SpeedSliderLabel.Enable = 'Off';
                app.SpeedEditField.Enable = 'Off';
                app.LoopContinuousCheckBox.Enable = 'Off';
                app.RunButton.Enable = 'Off';
            end
            %}
        end

        % Button pushed function: ChooseCh1Button
        function ChooseCh1ButtonPushed(app, event)
            app.Ch1Lamp.Color = [1.0 1.0 0.0];
            
            [fileName, pathName] = uigetfile('*.tif','Please select your data set');
            
            % Check if user clicked cancel
            if (fileName == 0)
                if (~app.filePath)
                    app.Ch1Lamp.Color = [1.0 0.0 0.0];
                else
                    app.Ch1Lamp.Color = [0.0 1.0 0.0];
                end
                
                return
            end
            
            app.filePath = pathName;
            ch1name=fileName(1:end-4);
            data = [app.filePath ch1name '.tif'];
            
            % Add path to code from parent folder
            addpath('current/STICS/');
            
            % Check if data is in MultiTiff files
            if (strcmp(fileName(end-2:end),'tif'))
                try
                    app.Ch1Stack = readFileToStack(data);
                catch Error
                    % Select another file
                    [app.StackFileName, app.StackPath] = uigetfile('*.tif', 'Select Stack File');
                    app.Ch1Stack = readFileToStack([app.StackPath, app.StackFileName]);
                end
                
                isValid = 1;
            else
                isValid = 0;
            end
            
            app.FramesSlider.Limits = [1, size(app.Ch1Stack, 3)];
            app.ofColorsSlider.Limits = [1, 255];
            app.ROISizeSlider.Limits = [1, size(app.Ch1Stack, 1) - 2];
            
            % Set image as first frame once file loaded
            file = app.Ch1Stack(:,:,1);
            
            imshow(file, [app.height, app.width], 'Parent', app.Ch1UIAxes);
          
            %{
            % Draw rectangle
            app.currRectangle = rectangle(app.Ch1UIAxes, 'Position', [(1 + app.ROISizeSlider.Value)/2,...
                (1 + app.ROISizeSlider.Value)/2, size(app.Ch1Stack,1)-app.ROISizeSlider.Value,...
                size(app.Ch1Stack,1)-app.ROISizeSlider.Value],...
                'FaceColor','none', 'LineStyle','--');
            %}
            
            % Draw movable ROI
            app.ROIsquare = drawrectangle(app.Ch1UIAxes, 'Position',...
                [1, 1, ceil(app.ROISizeSlider.Value), ceil(app.ROISizeSlider.Value)],...
                'AspectRatio', 1, 'FixedAspectRatio', true, 'InteractionsAllowed', 'none');
            app.currentROIcoors = app.ROIsquare.Position;
            
            app.ROIsquare.Position
            
            % Listen for ROI movement
            addlistener(app.ROIsquare, 'ROIMoved', @ROIChange);
            
            
            % Check if file valid
            if (isValid)
                % Enable adjustable fields
                %app.SelectDataButton.BackgroundColor = [0.33 0.77 0.01];
                app.Ch1Lamp.Color = [0.0 1.0 0.0];
                enableSliders(app);
                app.ChooseCh1Button.Enable = 'Off';
                app.ChooseCh1Button.Visible = 'Off';
                
            % File invalid
            else
                % Disable adjustable fields
                %app.SelectDataButton.BackgroundColor = [1.0 0.0 0.0];
                app.Ch1Lamp.Color = [1.0 0.0 0.0];
                disableSliders(app);
                app.ChooseCh1Button.Enable = 'On';
                app.ChooseCh1Button.Visible = 'On';
            end
        end

        % Button pushed function: ChooseCh2Button
        function ChooseCh2ButtonPushed(app, event)
            app.Ch2Lamp.Color = [1.0 1.0 0.0];
            
            [fileName, pathName] = uigetfile('*.tif','Please select your data set');
            
            % Check if user clicked cancel
            if (fileName == 0)
                if (~app.filePath)
                    app.Ch2Lamp.Color = [1.0 0.0 0.0];
                else
                    app.Ch2Lamp.Color = [0.0 1.0 0.0];
                end
                
                return
            end
            
            app.filePath = pathName;
            ch2name=fileName(1:end-4);
            data = [app.filePath ch2name '.tif'];
            
            % Add path to code from parent folder
            addpath('current/STICS/');
            
            % Check if data is in MultiTiff files
            if (strcmp(fileName(end-2:end),'tif'))
                try
                    app.Ch2Stack = readFileToStack(data);
                catch Error
                    % Select another file
                    [app.StackFileName, app.StackPath] = uigetfile('*.tif', 'Select Stack File');
                    app.Ch2Stack = readFileToStack([app.StackPath, app.StackFileName]);
                end
                
                isValid = 1;
            else
                isValid = 0;
            end
            
            app.FramesSlider.Limits = [1, size(app.Ch2Stack, 3)];
            app.ofColorsSlider.Limits = [1, 255];
            app.ROISizeSlider.Limits = [1, size(app.Ch2Stack, 1)];
            
            % Set image as first frame once file loaded
            file = app.Ch2Stack(:,:,1);

            imshow(file, [app.height, app.width], 'Parent', app.Ch2UIAxes);
            
            % Check if file valid
            if (isValid)
                % Enable adjustable fields
                %app.SelectDataButton.BackgroundColor = [0.33 0.77 0.01];
                app.Ch2Lamp.Color = [0.0 1.0 0.0];
                enableSliders(app);
                app.ChooseCh2Button.Enable = 'Off';
                app.ChooseCh2Button.Visible = 'Off';
                
            % File invalid
            else
                % Disable adjustable fields
                %app.SelectDataButton.BackgroundColor = [1.0 0.0 0.0];
                app.Ch2Lamp.Color = [1.0 0.0 0.0];
                disableSliders(app);
                app.ChooseCh2Button.Enable = 'On';
                app.ChooseCh2Button.Visible = 'On';
            end
        end

        % Value changing function: ROISizeSlider
        function ROISizeSliderValueChanging(app, event)
            changingValue = ceil(event.Value);
            
            %oldROIpos = app.currentROIcoors;
            delete(app.ROIsquare);
            
            % Draw re-sized ROI
            app.ROIsquare = drawrectangle(app.Ch1UIAxes, 'Position',...
                [1, 1, changingValue, changingValue],...
                'AspectRatio', 1, 'FixedAspectRatio', true, 'InteractionsAllowed', 'none');
            app.currentROIcoors = app.ROIsquare.Position;
            
            app.XPositionSlider.Limits = [0, size(app.Ch1Stack, 1) - changingValue - 1];
            app.YPositionSlider.Limits = [0, size(app.Ch1Stack, 2) - changingValue - 1];
            
            app.XPositionSlider.Value = 0;
            app.YPositionSlider.Value = 0;
        end

        % Value changed function: ROISizeSlider
        function ROISizeSliderValueChanged(app, event)
            value = ceil(app.ROISizeSlider.Value);
            
            %oldROIpos = app.currentROIcoors;
            delete(app.ROIsquare);
            
            % Draw re-sized ROI
            app.ROIsquare = drawrectangle(app.Ch1UIAxes, 'Position',...
                [1, 1, value, value],...
                'AspectRatio', 1, 'FixedAspectRatio', true, 'InteractionsAllowed', 'none');
            app.currentROIcoors = app.ROIsquare.Position;   
            
            app.XPositionSlider.Limits = [0, size(app.Ch1Stack, 1) - value - 1];
            app.YPositionSlider.Limits = [0, size(app.Ch1Stack, 2) - value - 1];
            
            app.XPositionSlider.Value = 0;
            app.YPositionSlider.Value = 0;
        end

        % Value changing function: XPositionSlider
        function XPositionSliderValueChanging(app, event)
            changingValue = ceil(event.Value);
            
            oldROIpos = app.currentROIcoors;
            delete(app.ROIsquare);
            
            % Draw re-sized ROI
            app.ROIsquare = drawrectangle(app.Ch1UIAxes, 'Position',...
                [changingValue, oldROIpos(2), oldROIpos(3), oldROIpos(4)],...
                'AspectRatio', 1, 'FixedAspectRatio', true, 'InteractionsAllowed', 'none');
            app.currentROIcoors = app.ROIsquare.Position;
        end

        % Value changed function: XPositionSlider
        function XPositionSliderValueChanged(app, event)
            value = ceil(app.XPositionSlider.Value);
            
            oldROIpos = app.currentROIcoors;
            delete(app.ROIsquare);
            
            % Draw re-sized ROI
            app.ROIsquare = drawrectangle(app.Ch1UIAxes, 'Position',...
                [value, oldROIpos(2), oldROIpos(3), oldROIpos(4)],...
                'AspectRatio', 1, 'FixedAspectRatio', true, 'InteractionsAllowed', 'none');
            app.currentROIcoors = app.ROIsquare.Position;
        end

        % Value changing function: YPositionSlider
        function YPositionSliderValueChanging(app, event)
            changingValue = ceil(event.Value);
            
            oldROIpos = app.currentROIcoors;
            delete(app.ROIsquare);
            
            % Draw re-sized ROI
            app.ROIsquare = drawrectangle(app.Ch1UIAxes, 'Position',...
                [oldROIpos(1), changingValue, oldROIpos(3), oldROIpos(4)],...
                'AspectRatio', 1, 'FixedAspectRatio', true, 'InteractionsAllowed', 'none');
            app.currentROIcoors = app.ROIsquare.Position;
        end

        % Value changed function: YPositionSlider
        function YPositionSliderValueChanged(app, event)
            value = ceil(app.YPositionSlider.Value);
            
            oldROIpos = app.currentROIcoors;
            delete(app.ROIsquare);
            
            % Draw re-sized ROI
            app.ROIsquare = drawrectangle(app.Ch1UIAxes, 'Position',...
                [oldROIpos(1), value, oldROIpos(3), oldROIpos(4)],...
                'AspectRatio', 1, 'FixedAspectRatio', true, 'InteractionsAllowed', 'none');
            app.currentROIcoors = app.ROIsquare.Position;
        end

        % Value changed function: ofChannelsSwitch
        function ofChannelsSwitchValueChanged(app, event)
            value = app.ofChannelsSwitch.Value;
            
            if (strcmp(value, '2'))
                app.ChooseCh2Button.Enable = 'On';
                app.Ch2Lamp.Enable = 'On';
            else
                app.ChooseCh2Button.Enable = 'Off';
                app.Ch2Lamp.Enable = 'Off';
            end
        end

        % Button pushed function: UpdatePlotsButton
        function UpdatePlotsButtonPushed(app, event)
            %roi = app.ROIsquare;
            oldROIpos = app.currentROIcoors;
            %newROIpos = dragrect(roi.Position);
            
            xPos = ceil(app.XPositionSlider.Value) + 1;
            yPos = ceil(app.YPositionSlider.Value) + 1;
            ROIsize = oldROIpos(3);
            
             % Check if colormap off
            if (strcmp(app.isColormapOn, 'Off'))
                file1 = app.Ch1Stack(yPos:(yPos + ROIsize),xPos:(xPos + ROIsize), ceil(app.FramesSlider.Value));
            % Colormap on
            else
                % NEED TO CREATE A NEW FUNCTION FOR COLORMAP
                %file1 = colorMapChange(app, app.FramesSlider.Value, ceil(app.ofColorsSlider.Value));
            end
            
            imshow(file1, [app.height, app.width], 'Parent', app.Ch1ROIUIAxes)
            
            
        end

        % Button pushed function: ClearDataButton
        function ClearDataButtonPushed(app, event)
            deleteImage(app, app.Ch1UIAxes);
            deleteImage(app, app.Ch1ROIUIAxes);
            deleteImage(app, app.Ch2UIAxes);
            deleteImage(app, app.Ch2ROIUIAxes);
            
            resetAxes(app);
            
            app.ChooseCh1Button.Enable = 'On';
            app.ChooseCh1Button.Visible = 'On';
            app.ChooseCh2Button.Enable = 'On';
            app.ChooseCh2Button.Visible = 'On';
            
            app.Ch1Lamp.Color = [1.0 0.0 0.0];
            app.Ch2Lamp.Color = [1.0 0.0 0.0];
            
            app.filePath = '';
            app.Ch1Stack = '';
            app.Ch2Stack = '';
            
            disableSliders(app);
        end

        % Value changing function: FramesSlider
        function FramesSliderValueChanging(app, event)
            % Force value to round up
            changingValue = ceil(event.Value);
            
            oldROIpos = app.currentROIcoors;
            %newROIpos = dragrect(roi.Position);
            
            xPos = ceil(app.XPositionSlider.Value) + 1;
            yPos = ceil(app.YPositionSlider.Value) + 1;
            ROIsize = oldROIpos(3);
            
            % Check if colormap off
            if (strcmp(app.isColormapOn, 'Off'))
                file1 = app.Ch1Stack(:,:,changingValue);
                file1ROI = app.Ch1Stack(yPos:(yPos + ROIsize),xPos:(xPos + ROIsize), changingValue);   
            % Colormap on
            else
                % ADD NEW FUNCTION FOR COLOR MAP
                
                %file = ind2rgb(app.Stack(:,:,changingValue), jet(app.ofColorsSlider.Value));
                %file = uint8(255 * mat2gray(app.Stack(:,:,changingValue)));
                %file = colorMapChange(app, changingValue, ceil(app.ofColorsSlider.Value));
              
                %file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,changingValue))), cool(ceil(app.ofColorsSlider.Value)));
                %file1 = app.Ch1Stack(yPos:(yPos + ROIsize),xPos:(xPos + ROIsize),app.FramesSlider.Value);
            end
            
            imshow(file1, [app.height, app.width], 'Parent', app.Ch1UIAxes);
            imshow(file1ROI, [app.height, app.width], 'Parent', app.Ch1ROIUIAxes);
            % imshow(app.Stack(:,:,changingValue), [100, 1000], 'Parent', app.DataUIAxes);
            % hold on;
            %{
            rectangle(app.DataUIAxes, 'Position', [(1+app.Opt.ROIsize)/2, (1+app.Opt.ROIsize)/2,...
                size(app.Stack,1)-app.Opt.ROIsize, size(app.Stack,1)-app.Opt.ROIsize],...
                'FaceColor','none', 'LineStyle','--');
            rectangle(app.DataUIAxes, 'Position', [((app.XIndexSlider.Value-1)*app.Opt.ROIshift)+1/2,...
               ((app.YIndexSlider.Value-1)*app.Opt.ROIshift)+1/2,...
               app.Opt.ROIsize, app.Opt.ROIsize]);
            %}
            
            %{
            % Adjust FramesEditField
            if (app.FramesEditField.Value ~= changingValue)
                app.FramesEditField.Value = changingValue;
            end
            %}
            
            oldROIpos = app.currentROIcoors;
            delete(app.ROIsquare);
            
            % Re-draw ROI region
            app.ROIsquare = drawrectangle(app.Ch1UIAxes, 'Position',...
                [oldROIpos(1), oldROIpos(2), oldROIpos(3), oldROIpos(4)],...
                'AspectRatio', 1, 'FixedAspectRatio', true, 'InteractionsAllowed', 'none');
            app.currentROIcoors = app.ROIsquare.Position;
            
        end

        % Value changed function: FramesEditField
        function FramesEditFieldValueChanged(app, event)
            value = ceil(app.FramesEditField.Value);
            
            
            % FIX THIS
            
            oldROIpos = app.currentROIcoors;
            %newROIpos = dragrect(roi.Position);
            
            xPos = app.XPositionSlider.Value + 1;
            yPos = app.YPositionSlider.Value + 1;
            ROIsize = oldROIpos(3);
            
            % Check if colormap off
            if (strcmp(app.isColormapOn, 'Off'))
                file = app.Stack(:,:,value);
            % Colormap on
            else
                %file = ind2rgb(app.Stack(:,:,value), jet(app.ofColorsSlider.Value));
                %file = uint8(255 * mat2gray(app.Stack(:,:,value)));
                
                file = colorMapChange(app, value, ceil(app.ofColorsSlider.Value));
              
                %file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,value))), cool(ceil(app.ofColorsSlider.Value)));
            end
            
            imshow(file, [app.height, app.width], 'Parent', app.DataUIAxes);
            
            % Adjust slider value
            if (app.FramesSlider.Value ~= value)
                app.FramesSlider.Value = value;
            end
        end

        % Value changing function: ofColorsSlider
        function ofColorsSliderValueChanging(app, event)
            changingValue = ceil(event.Value);
            
            % Show changing colormap values if bool = true
            if (strcmp(app.isColormapOn, 'On'))
                %file = ind2rgb(app.Stack(:,:,app.FramesSlider.Value), jet(changingValue));
                
                file = colorMapChange(app, ceil(app.FramesSlider.Value), changingValue);
               
                %file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,ceil(app.FramesSlider.Value)))), cool(changingValue));
                %file = ind2rgb(app.Stack(:,:,ceil(app.FramesSlider.Value)), cool(changingValue));
                imshow(file, [app.height, app.width], 'Parent', app.DataUIAxes);
            end
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            app.isColormapOn = value;
            
            % Check if switch flipped to on
            if (strcmp(value, 'On'))
                %imshow(ind2rgb(app.Stack(:,:,ceil(app.FramesSlider.Value)), jet(app.ofColorsSlider.Value)), [100, 900], 'Parent', app.DataUIAxes);
                %imshow(uint8(255 * mat2gray(app.Stack(:,:,ceil(app.FramesSlider.Value)))), [100, 900], 'Parent', app.DataUIAxes);
                %imshow(ind2rgb(rescale(app.Stack(:,:,ceil(app.FramesSlider.Value))), jet(app.ofColorsSlider.Value)), [100, 900], 'Parent', app.DataUIAxes);
                %file = imagesc(rescale(app.Stack(:,:,ceil(app.FramesSlider.Value))), 'Visible', 'off');
                %imshow(file, [100, 900], 'Parent', app.DataUIAxes);
                %file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,ceil(app.FramesSlider.Value)))), cool(ceil(app.ofColorsSlider.Value)));
                
                file = colorMapChange(app, ceil(app.FramesSlider.Value), ceil(app.ofColorsSlider.Value));
               
                
                imshow(file, [app.height, app.width], 'Parent', app.DataUIAxes);
            % Switch off
            else
                imshow(app.Stack(:,:,ceil(app.FramesSlider.Value)), [app.height, app.width], 'Parent', app.DataUIAxes);
            end
            
        end

        % Selection changed function: ColormapOptionsButtonGroup
        function ColormapOptionsButtonGroupSelectionChanged(app, event)
            % Check if colormap on when new option clicked
            if (strcmp(app.isColormapOn, 'On'))
                file = colorMapChange(app, ceil(app.FramesSlider.Value), ceil(app.ofColorsSlider.Value));
                imshow(file, [100, 900], 'Parent', app.DataUIAxes);
            end
            
        end

        % Value changed function: FrameSelectionCheckBox
        function FrameSelectionCheckBoxValueChanged(app, event)
            value = app.FrameSelectionCheckBox.Value;
            
            app.isFrameSelectionEnabled = value;
            
            if (value)
                app.StartFrameEditField.Enable = 'On';
                app.StartFrameEditFieldLabel.Enable = 'On';
                app.EndFrameEditField.Enable = 'On';
                app.EndFrameEditFieldLabel.Enable = 'On';
            else
                app.StartFrameEditField.Enable = 'Off';
                app.StartFrameEditFieldLabel.Enable = 'Off';
                app.EndFrameEditField.Enable = 'Off';
                app.EndFrameEditFieldLabel.Enable = 'Off';
            end
        end

        % Value changed function: StartFrameEditField
        function StartFrameEditFieldValueChanged(app, event)
            value = app.StartFrameEditField.Value;
            
            % Check if start frame > end frame
            if (value >= app.EndFrameEditField.Value && value >= 1)
                app.EndFrameEditField.Value = value + 1;
            elseif (value < 1)
                app.StartFrameEditField.Value = 1;
            end
            
        end

        % Value changed function: EndFrameEditField
        function EndFrameEditFieldValueChanged(app, event)
            value = app.EndFrameEditField.Value;
            
            if (value <= app.StartFrameEditField.Value && value <= size(app.Stack, 3))
                app.StartFrameEditField.Value = value - 1;
            elseif (value > size(app.Stack, 3))
                app.EndFrameEditField.Value = size(app.Stack, 3);
            end
            
        end

        % Value changing function: SpeedSlider
        function SpeedSliderValueChanging(app, event)
            changingValue = round(event.Value, 2);
            
            app.SpeedEditField.Value = changingValue;
            app.stackSpeed = changingValue;
        end

        % Value changed function: SpeedEditField
        function SpeedEditFieldValueChanged(app, event)
            value = round(app.SpeedEditField.Value, 2);
            
            if (value < 0.3)
                app.SpeedEditField.Value = 0.3;
            elseif (value > 5)
                app.SpeedEditField.Value = 5.0;
            end
            
            app.stackSpeed = app.SpeedEditField.Value;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.RunButton.Enable = 'Off';
            app.SpeedSlider.Enable = 'Off';
            app.SpeedEditField.Enable = 'Off';
            
            % Loop continuous checked
            if (app.LoopContinuousCheckBox.Value)
                check = checkContinuous(app);
                % Keep checking if continuous looping checked
                while (check)
                    % Check if frame chunk selected
                    if (app.isFrameSelectionEnabled)
                        showFrames(app, app.StartFrameEditField.Value, app.EndFrameEditField.Value);
                    else
                        showFrames(app, 1, size(app.Stack, 3));

                        %{
                            if (i == size(app.Stack, 3))
                                pause(0.2);
                                % Check if continuous looping checked
                                if (app.LoopContinuousCheckBox.Value)
                                    RunButtonPushed(app, 2);
                                end
                            end
                            %}
                    end
                    
                    pause(0.2);
                    check = checkContinuous(app);
                    
                    if (~check)
                        break;
                    end
                    
                    pause(0.1)
                end
            % Loop continuous not checked
            else
                % Check if frame chunk selected
                if (app.isFrameSelectionEnabled)
                    showFrames(app, app.StartFrameEditField.Value, app.EndFrameEditField.Value);
                else
                    showFrames(app, 1, size(app.Stack, 3));
                end
            end
            
            app.RunButton.Enable = 'On';
            app.SpeedSlider.Enable = 'On';
            app.SpeedEditField.Enable = 'On';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.DataUIAxes = uiaxes(app.UIFigure);
            app.DataUIAxes.XTick = [];
            app.DataUIAxes.YTick = [];
            app.DataUIAxes.Visible = 'off';
            app.Ch1UIAxes = uiaxes(app.UIFigure);
            app.Ch1UIAxes.XTick = [];
            app.Ch1UIAxes.YTick = [];
            app.Ch1ROIUIAxes = uiaxes(app.UIFigure);
            app.Ch1ROIUIAxes.XTick = [];
            app.Ch1ROIUIAxes.YTick = [];
            app.Ch2UIAxes = uiaxes(app.UIFigure);
            app.Ch2UIAxes.XTick = [];
            app.Ch2UIAxes.YTick = [];
            app.Ch2ROIUIAxes = uiaxes(app.UIFigure);
            app.Ch2ROIUIAxes.XTick = [];
            app.Ch2ROIUIAxes.YTick = [];
            app.SelectDataButton = uibutton(app.UIFigure, 'push');
            app.SelectDataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDataButtonPushed, true);
            app.SelectDataButton.Enable = 'off';
            app.SelectDataButton.Visible = 'off';
            app.SelectDataButton.Text = 'Select Data';
            app.FramesSliderLabel = uilabel(app.UIFigure);
            app.FramesSliderLabel.Enable = 'off';
            app.FramesSliderLabel.Text = 'Frames';
            app.FramesSlider = uislider(app.UIFigure);
            app.FramesSlider.Limits = [1 100];
            app.FramesSlider.ValueChangingFcn = createCallbackFcn(app, @FramesSliderValueChanging, true);
            app.FramesSlider.Enable = 'off';
            app.FramesSlider.Value = 1;
            app.FramesEditField = uieditfield(app.UIFigure, 'numeric');
            app.FramesEditField.ValueChangedFcn = createCallbackFcn(app, @FramesEditFieldValueChanged, true);
            app.FramesEditField.Enable = 'off';
            app.FramesEditField.Value = 1;
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Enable = 'off';
            app.ColormapLabel = uilabel(app.UIFigure);
            app.ColormapLabel.Text = 'Colormap';
            app.ofColorsSliderLabel = uilabel(app.UIFigure);
            app.ofColorsSliderLabel.Enable = 'off';
            app.ofColorsSliderLabel.Text = '# of Colors';
            app.ofColorsSlider = uislider(app.UIFigure);
            app.ofColorsSlider.Limits = [1 255];
            app.ofColorsSlider.ValueChangingFcn = createCallbackFcn(app, @ofColorsSliderValueChanging, true);
            app.ofColorsSlider.Enable = 'off';
            app.ofColorsSlider.Value = 255;
            app.ColormapOptionsButtonGroup = uibuttongroup(app.UIFigure);
            app.ColormapOptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColormapOptionsButtonGroupSelectionChanged, true);
            app.ColormapOptionsButtonGroup.Enable = 'off';
            app.ColormapOptionsButtonGroup.Title = 'Colormap Options';
            app.ParulaButton = uiradiobutton(app.ColormapOptionsButtonGroup);
            app.ParulaButton.Text = 'Parula';
            app.ParulaButton.Value = true;
            app.TurboButton = uiradiobutton(app.ColormapOptionsButtonGroup);
            app.TurboButton.Text = 'Turbo';
            app.HSVButton = uiradiobutton(app.ColormapOptionsButtonGroup);
            app.HSVButton.Text = 'HSV';
            app.CoolButton = uiradiobutton(app.ColormapOptionsButtonGroup);
            app.CoolButton.Text = 'Cool';
            app.JetButton = uiradiobutton(app.ColormapOptionsButtonGroup);
            app.JetButton.Text = 'Jet';
            app.HotButton = uiradiobutton(app.ColormapOptionsButtonGroup);
            app.HotButton.Text = 'Hot';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.LoopContinuousCheckBox = uicheckbox(app.UIFigure);
            app.LoopContinuousCheckBox.Enable = 'off';
            app.LoopContinuousCheckBox.Text = 'Loop Continuous';
            app.StartFrameEditFieldLabel = uilabel(app.UIFigure);
            app.StartFrameEditFieldLabel.Enable = 'off';
            app.StartFrameEditFieldLabel.Text = 'Start Frame';
            app.StartFrameEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartFrameEditField.ValueChangedFcn = createCallbackFcn(app, @StartFrameEditFieldValueChanged, true);
            app.StartFrameEditField.Enable = 'off';
            app.StartFrameEditField.Value = 1;
            app.EndFrameEditFieldLabel = uilabel(app.UIFigure);
            app.EndFrameEditFieldLabel.Enable = 'off';
            app.EndFrameEditFieldLabel.Text = 'End Frame';
            app.EndFrameEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndFrameEditField.ValueChangedFcn = createCallbackFcn(app, @EndFrameEditFieldValueChanged, true);
            app.EndFrameEditField.Enable = 'off';
            app.EndFrameEditField.Value = 2;
            app.FrameSelectionCheckBox = uicheckbox(app.UIFigure);
            app.FrameSelectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @FrameSelectionCheckBoxValueChanged, true);
            app.FrameSelectionCheckBox.Enable = 'off';
            app.FrameSelectionCheckBox.Text = 'Frame Selection';
            app.SpeedSliderLabel = uilabel(app.UIFigure);
            app.SpeedSliderLabel.Enable = 'off';
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.UIFigure);
            app.SpeedSlider.Limits = [0.3 5];
            app.SpeedSlider.ValueChangingFcn = createCallbackFcn(app, @SpeedSliderValueChanging, true);
            app.SpeedSlider.Enable = 'off';
            app.SpeedSlider.Value = 1;
            app.SpeedEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpeedEditField.ValueChangedFcn = createCallbackFcn(app, @SpeedEditFieldValueChanged, true);
            app.SpeedEditField.Enable = 'off';
            app.SpeedEditField.Value = 1;
            app.ChooseCh1Button = uibutton(app.UIFigure, 'push');
            app.ChooseCh1Button.ButtonPushedFcn = createCallbackFcn(app, @ChooseCh1ButtonPushed, true);
            app.ChooseCh1Button.Text = 'Select Channel 1';
            app.ChooseCh2Button = uibutton(app.UIFigure, 'push');
            app.ChooseCh2Button.ButtonPushedFcn = createCallbackFcn(app, @ChooseCh2ButtonPushed, true);
            app.ChooseCh2Button.Enable = 'off';
            app.ChooseCh2Button.Text = 'Select Channel 2';
            app.Ch1Lamp = uilamp(app.UIFigure);
            app.Ch2Lamp = uilamp(app.UIFigure);
            app.Ch2Lamp.Enable = 'off';
            app.ClearDataButton = uibutton(app.UIFigure, 'push');
            app.ClearDataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearDataButtonPushed, true);
            app.ClearDataButton.Text = 'Clear Data';
            app.ROISizeSliderLabel = uilabel(app.UIFigure);
            app.ROISizeSliderLabel.Enable = 'off';
            app.ROISizeSliderLabel.Text = 'ROI Size';
            app.ROISizeSlider = uislider(app.UIFigure);
            app.ROISizeSlider.ValueChangedFcn = createCallbackFcn(app, @ROISizeSliderValueChanged, true);
            app.ROISizeSlider.ValueChangingFcn = createCallbackFcn(app, @ROISizeSliderValueChanging, true);
            app.ROISizeSlider.Enable = 'off';
            app.UpdatePlotsButton = uibutton(app.UIFigure, 'push');
            app.UpdatePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePlotsButtonPushed, true);
            app.UpdatePlotsButton.Text = 'Update Plots';
            app.ofChannelsSwitchLabel = uilabel(app.UIFigure);
            app.ofChannelsSwitchLabel.Text = '# of Channels';
            app.ofChannelsSwitch = uiswitch(app.UIFigure, 'slider');
            app.ofChannelsSwitch.Items = {'1', '2'};
            app.ofChannelsSwitch.ValueChangedFcn = createCallbackFcn(app, @ofChannelsSwitchValueChanged, true);
            app.ofChannelsSwitch.Value = '1';
            app.XPositionSliderLabel = uilabel(app.UIFigure);
            app.XPositionSliderLabel.Enable = 'off';
            app.XPositionSliderLabel.Text = 'X Position';
            app.XPositionSlider = uislider(app.UIFigure);
            app.XPositionSlider.ValueChangedFcn = createCallbackFcn(app, @XPositionSliderValueChanged, true);
            app.XPositionSlider.ValueChangingFcn = createCallbackFcn(app, @XPositionSliderValueChanging, true);
            app.XPositionSlider.Enable = 'off';
            app.YPositionSliderLabel = uilabel(app.UIFigure);
            app.YPositionSliderLabel.Enable = 'off';
            app.YPositionSliderLabel.Text = 'Y Position';
            app.YPositionSlider = uislider(app.UIFigure);
            app.YPositionSlider.ValueChangedFcn = createCallbackFcn(app, @YPositionSliderValueChanged, true);
            app.YPositionSlider.ValueChangingFcn = createCallbackFcn(app, @YPositionSliderValueChanging, true);
            app.YPositionSlider.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FilePreviewer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end