classdef ICS_Explorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        NavigatorPanel        matlab.ui.container.Panel
        YindexSpinner         matlab.ui.control.Spinner
        YindexSpinnerLabel    matlab.ui.control.Label
        XindexSpinner         matlab.ui.control.Spinner
        XindexSpinnerLabel    matlab.ui.control.Label
        FrameSpinner          matlab.ui.control.Spinner
        FrameSpinnerLabel     matlab.ui.control.Label
        ZoomSpinner           matlab.ui.control.Spinner
        ZoomSpinnerLabel      matlab.ui.control.Label
        PopButton             matlab.ui.control.Button
        UpdateButton          matlab.ui.control.Button
        ViewmodeSwitch        matlab.ui.control.Switch
        Preview               matlab.ui.control.UIAxes
        SelectPanel           matlab.ui.container.Panel
        Label_4               matlab.ui.control.Label
        Label_3               matlab.ui.control.Label
        Label_2               matlab.ui.control.Label
        Ch1Label              matlab.ui.control.Label
        CorrelationLabel      matlab.ui.control.Label
        CheckBox_4            matlab.ui.control.CheckBox
        CheckBox_3            matlab.ui.control.CheckBox
        CheckBox_2            matlab.ui.control.CheckBox
        CheckBox              matlab.ui.control.CheckBox
        VecMapLamp            matlab.ui.control.Lamp
        StackLamp             matlab.ui.control.Lamp
        StackButton           matlab.ui.control.Button
        VectormapButton       matlab.ui.control.Button
        SelectButton          matlab.ui.control.Button
        DisplaydatatotalofvectorsROITOIsizeetcLabel  matlab.ui.control.Label
        UIndexSlider          matlab.ui.control.Slider
        UIndexSliderLabel     matlab.ui.control.Label
        YIndexSlider          matlab.ui.control.Slider
        YIndexSliderLabel     matlab.ui.control.Label
        XIndexSlider          matlab.ui.control.Slider
        XIndexSliderLabel     matlab.ui.control.Label
        FrameSlider           matlab.ui.control.Slider
        FrameSliderLabel      matlab.ui.control.Label
        CenterPanel           matlab.ui.container.Panel
        TauSlider             matlab.ui.control.Slider
        TauSliderLabel        matlab.ui.control.Label
        ICSExplorerLabel      matlab.ui.control.Label
        FitmodeDropDown       matlab.ui.control.DropDown
        FitmodeDropDownLabel  matlab.ui.control.Label
        FitstatisticsGaussianpropertiesandgoodnessoffitLabel  matlab.ui.control.Label
        ICF3D                 matlab.ui.control.UIAxes
        RightPanel            matlab.ui.container.Panel
        ICF2D                 matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        Opt
        PosX
        PosY
        PosT
        Stack
        VelMap
        VelMapFileName
        VelMapPath % Path to the velocity map
        CallingApp;
        k % Description
    end
    
    properties (Access = private)
        CurrentFrame
        CurrentPosX
        CurrentPosY
        MinFrame % Frame number of data that corresponds to the first vector field frame
        MaxFrame % Frame number of data that corresponds to the last vector field frame
        StackFileName
        StackPath
        ROIsquare % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, STICSgui1)
%             app.CallingApp = STICSgui1;
        end

        % Button pushed function: VectormapButton
        function VectormapButtonPushed(app, event)
            app.VecMapLamp.Color = [0.93,0.69,0.13];
            
            % Select and load data
            [app.VelMapFileName, app.VelMapPath] = uigetfile('*.mat', 'Select the Velocity Map');
            load([app.VelMapPath, app.VelMapFileName], '-mat');
            app.VelMap = velocityMap;
            app.PosX = position_x;
            app.PosY = position_y;
            app.PosT = position_t;
            app.Opt = opt;
            
            % Check if cancel was clicked            
            if isempty(app.VelMap)
                app.StackLamp.Color = 'r';
                return;
            else
                app.StackLamp.Color = 'g';
            end
            
            % Set Frame range
            app.MinFrame = ceil(app.Opt.TOIsize/2);
            app.MaxFrame = size(app.Stack, 3) - floor(app.Opt.TOIsize/2);            
            app.FrameSpinner.Limits = [app.MinFrame, app.MaxFrame];

            % Set vector field index range
            app.XindexSpinner.Limits = [1, size(app.PosX, 1)];
            app.YindexSpinner.Limits = [1, size(app.PosY, 2)];
            app.UIndexSlider.Limits = [1, size(app.PosX, 1)*size(app.PosY, 2)];
            
            % Set Tau range
            app.TauSlider.Limits = [0, app.Opt.TOIsize]; % May change later
            
            % Get current position
            vix = app.XindexSpinner.Value;
            app.CurrentPosX = app.PosX(vix, 1);
            viy = app.YindexSpinner.Value;
            app.CurrentPosY = app.PosY(1, viy);
            
            % Draw rectangles
            rectangle(app.Preview, 'Position', [(1+app.Opt.ROIsize)/2, (1+app.Opt.ROIsize)/2,...
                size(app.Stack,1)-app.Opt.ROIsize, size(app.Stack,1)-app.Opt.ROIsize],...
                'FaceColor','none', 'LineStyle','--');
            rectangle(app.Preview,'Position',...
                [app.CurrentPosX-opt.ROIsize/2,...
                app.CurrentPosY-opt.ROIsize/2,...
                opt.ROIsize, opt.ROIsize]);
            app.ROIsquare = drawrectangle(app.Preview, "Position",...
                [app.CurrentPosX-opt.ROIsize/2,...
                app.CurrentPosY-opt.ROIsize/2,...
                opt.ROIsize, opt.ROIsize],...
                "AspectRatio",1,"FixedAspectRatio",true,"InteractionsAllowed","translate");
%             How to plot the dots!!??
%             scatter(app.Preview, app.CurrentPosX, app.CurrentPosY,'+y', 'LineWidth', 1);           
%             plot(app.Preview, app.PosX, app.PosY,'.r'); 
 
            app.VecMapLamp.Color = 'g';
            
            app.UIndexSlider.Enable = 'On';
            app.UIndexSliderLabel.Enable = 'On';
            app.YIndexSlider.Enable = 'On';
            app.YIndexSliderLabel.Enable = 'On';
            app.XIndexSlider.Enable = 'On';
            app.XIndexSliderLabel.Enable = 'On';
            app.FrameSlider.Enable = 'On';
            app.FrameSliderLabel.Enable = 'On';


        end

        % Button down function: CenterPanel
        function CenterPanelButtonDown(app, event)
            
        end

        % Button pushed function: UpdateButton
        function UpdatePreviewButtonPushed(app, event)
            roi = app.ROIsquare;
            newROIpos = dragrect(roi.Position);
            
            imshow(app.Stack(:,:,app.FrameSpinner.Value), [100, 1000], 'Parent', app.Preview);
            rectangle(app.Preview, 'Position', [(1+app.Opt.ROIsize)/2, (1+app.Opt.ROIsize)/2,...
                size(app.Stack,1)-app.Opt.ROIsize, size(app.Stack,1)-app.Opt.ROIsize],...
                'FaceColor','none', 'LineStyle','--');


%             if newROIpos ~= roi.Position
                userCurrX = (newROIpos(1) + (newROIpos(3)/2));
                [~, index] = min(abs(userCurrX - app.PosX(:,1)));
                snapCurrX = app.PosX(index,1);
    %             complicated way:
    %             position_x(position_x(:,1) == userCurrX + min(abs(userCurrX - position_x(:,1))))
                app.XindexSpinner.Value = index;
                
                
                userCurrY = (newROIpos(2) + (newROIpos(4)/2));
                [~, index] = min(abs(userCurrY - app.PosY(1,:)));
                snapCurrY = app.PosY(1,index);
    %             complicated way:
    %             position_x(position_x(:,1) == userCurrX + min(abs(userCurrX - position_x(:,1))))
                app.YindexSpinner.Value = index;
                
                finalROIpos = [snapCurrX, snapCurrY, app.Opt.ROIsize, app.Opt.ROIsize];
                app.CurrentPosX = snapCurrX;
                app.CurrentPosY = snapCurrY;
%             end
            rectangle(app.Preview,'Position', finalROIpos); 
            app.ROIsquare = drawrectangle(app.Preview,...
                "Position", finalROIpos, "AspectRatio", 1,...
                "FixedAspectRatio",true,"InteractionsAllowed","translate");
        end

        % Value changing function: UIndexSlider
        function UIndexSliderValueChanging(app, event)
            changingValue = ceil(event.Value);
            
            y_val = double(idivide(uint16(changingValue), size(app.PosY,2)) + 1)
            x_val = double(mod(changingValue, size(app.PosY,2)))
            
            %mod(changingValue,app.YIndexSlider.Value)
            
            if (y_val > app.YIndexSlider.Limits(2))
                return;
            end
            
            app.YIndexSlider.Value = y_val;
            app.XIndexSlider.Value = x_val;
            
            %app.YIndexSlider.Value = idivide(int16(changingValue),int16(size(app.PosY,2))) + 1;
            %app.XIndexSlider.Value = mod(changingValue,app.YIndexSlider.Value);
        end

        % Value changing function: XIndexSlider
        function XIndexSliderValueChanging(app, event)
            changingValue = event.Value;
            app.UIndexSlider.Value = size(app.PosX,1)*app.YIndexSlider.Value + changingValue;
        end

        % Value changing function: YIndexSlider
        function YIndexSliderValueChanging(app, event)
            changingValue = event.Value;
            app.UIndexSlider.Value = size(app.PosX,1)*changingValue + app.XIndexSlider.Value;
        end

        % Value changed function: XIndexSlider
        function XIndexSliderValueChanged(app, event)
            value = app.XIndexSlider.Value;
            %app.CurrentPosX = app.PosX(value, 1);
            app.CurrentPosX = value;
        end

        % Value changed function: YIndexSlider
        function YIndexSliderValueChanged(app, event)
            value = app.YIndexSlider.Value;
            %app.CurrentPosY = app.PosY(1, value);
            app.CurrentPosY = value;
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            value = app.FrameSlider.Value;
            app.CurrentFrame = value;
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);
        end

        % Button pushed function: StackButton
        function StackButtonPushed(app, event)
            app.StackLamp.Color = [0.93,0.69,0.13];
            [app.StackFileName, app.StackPath] = uigetfile("*.tif");
            app.Stack = readFileToStack([app.StackPath, app.StackFileName]);
            if isempty(app.Stack)
                app.StackLamp.Color = 'r';
            else
                app.StackLamp.Color = 'g';
                imshow(app.Stack(:,:,app.FrameSpinner.Value), [100, 1000], 'Parent', app.Preview);
%                 ax = axes(app.Preview); % Trying to set axis tight to
%                 make the image bigger
%                 ax.
            end
            % Make the app to be brought back to the front!
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {705, 705, 705};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {705, 705};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {275, '1x', 534};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FrameSliderLabel = uilabel(app.LeftPanel);
            app.FrameSliderLabel.Enable = 'off';
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.LeftPanel);
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.FrameSlider.Enable = 'off';
            app.XIndexSliderLabel = uilabel(app.LeftPanel);
            app.XIndexSliderLabel.Enable = 'off';
            app.XIndexSliderLabel.Text = 'X Index';
            app.XIndexSlider = uislider(app.LeftPanel);
            app.XIndexSlider.ValueChangedFcn = createCallbackFcn(app, @XIndexSliderValueChanged, true);
            app.XIndexSlider.ValueChangingFcn = createCallbackFcn(app, @XIndexSliderValueChanging, true);
            app.XIndexSlider.Enable = 'off';
            app.YIndexSliderLabel = uilabel(app.LeftPanel);
            app.YIndexSliderLabel.Enable = 'off';
            app.YIndexSliderLabel.Text = 'Y index';
            app.YIndexSlider = uislider(app.LeftPanel);
            app.YIndexSlider.ValueChangedFcn = createCallbackFcn(app, @YIndexSliderValueChanged, true);
            app.YIndexSlider.ValueChangingFcn = createCallbackFcn(app, @YIndexSliderValueChanging, true);
            app.YIndexSlider.Enable = 'off';
            app.UIndexSliderLabel = uilabel(app.LeftPanel);
            app.UIndexSliderLabel.Enable = 'off';
            app.UIndexSliderLabel.Text = 'U index';
            app.UIndexSlider = uislider(app.LeftPanel);
            app.UIndexSlider.ValueChangingFcn = createCallbackFcn(app, @UIndexSliderValueChanging, true);
            app.UIndexSlider.Enable = 'off';
            app.DisplaydatatotalofvectorsROITOIsizeetcLabel = uilabel(app.LeftPanel);
            app.DisplaydatatotalofvectorsROITOIsizeetcLabel.Text = 'Display data (total # of vectors, ROI & TOI size, etc)';
            app.SelectButton = uibutton(app.LeftPanel, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.SelectPanel = uipanel(app.LeftPanel);
            app.SelectPanel.Title = 'Select';
            app.VectormapButton = uibutton(app.SelectPanel, 'push');
            app.VectormapButton.ButtonPushedFcn = createCallbackFcn(app, @VectormapButtonPushed, true);
            app.VectormapButton.Text = 'Vector map';
            app.StackButton = uibutton(app.SelectPanel, 'push');
            app.StackButton.ButtonPushedFcn = createCallbackFcn(app, @StackButtonPushed, true);
            app.StackButton.Text = 'Stack';
            app.StackLamp = uilamp(app.SelectPanel);
            app.VecMapLamp = uilamp(app.SelectPanel);
            app.CheckBox = uicheckbox(app.SelectPanel);
            app.CheckBox.Enable = 'off';
            app.CheckBox.Text = '';
            app.CheckBox.Value = true;
            app.CheckBox_2 = uicheckbox(app.SelectPanel);
            app.CheckBox_2.Enable = 'off';
            app.CheckBox_2.Text = '';
            app.CheckBox_3 = uicheckbox(app.SelectPanel);
            app.CheckBox_3.Enable = 'off';
            app.CheckBox_3.Text = '';
            app.CheckBox_4 = uicheckbox(app.SelectPanel);
            app.CheckBox_4.Enable = 'off';
            app.CheckBox_4.Text = '';
            app.CorrelationLabel = uilabel(app.SelectPanel);
            app.CorrelationLabel.Text = 'Correlation';
            app.Ch1Label = uilabel(app.SelectPanel);
            app.Ch1Label.Text = 'Ch 1';
            app.Label_2 = uilabel(app.SelectPanel);
            app.Label_2.Text = '1';
            app.Label_3 = uilabel(app.SelectPanel);
            app.Label_3.Text = '2';
            app.Label_4 = uilabel(app.SelectPanel);
            app.Label_4.Text = '2';
            app.NavigatorPanel = uipanel(app.LeftPanel);
            app.NavigatorPanel.Title = 'Navigator';
            app.Preview = uiaxes(app.NavigatorPanel);
            zlabel(app.Preview, 'Z')
            app.Preview.XLimitMethod = 'tight';
            app.Preview.YLimitMethod = 'tight';
            app.ViewmodeSwitch = uiswitch(app.NavigatorPanel, 'slider');
            app.ViewmodeSwitch.Items = {'2D', '3D'};
            app.ViewmodeSwitch.Value = '2D';
            app.UpdateButton = uibutton(app.NavigatorPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePreviewButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.PopButton = uibutton(app.NavigatorPanel, 'push');
            app.PopButton.Text = 'Pop';
            app.ZoomSpinnerLabel = uilabel(app.NavigatorPanel);
            app.ZoomSpinnerLabel.Text = 'Zoom';
            app.ZoomSpinner = uispinner(app.NavigatorPanel);
            app.ZoomSpinner.Limits = [1 100];
            app.ZoomSpinner.Value = 1;
            app.FrameSpinnerLabel = uilabel(app.NavigatorPanel);
            app.FrameSpinnerLabel.Text = 'Frame';
            app.FrameSpinner = uispinner(app.NavigatorPanel);
            app.FrameSpinner.Limits = [1 100];
            app.FrameSpinner.Value = 1;
            app.XindexSpinnerLabel = uilabel(app.NavigatorPanel);
            app.XindexSpinnerLabel.Text = 'X index';
            app.XindexSpinner = uispinner(app.NavigatorPanel);
            app.XindexSpinner.Limits = [1 100];
            app.XindexSpinner.Value = 1;
            app.YindexSpinnerLabel = uilabel(app.NavigatorPanel);
            app.YindexSpinnerLabel.Text = 'Y index';
            app.YindexSpinner = uispinner(app.NavigatorPanel);
            app.YindexSpinner.Limits = [1 100];
            app.YindexSpinner.Value = 1;
            app.CenterPanel = uipanel(app.GridLayout);
            app.CenterPanel.ButtonDownFcn = createCallbackFcn(app, @CenterPanelButtonDown, true);
            app.ICF3D = uiaxes(app.CenterPanel);
            title(app.ICF3D, '3D surface plot')
            xlabel(app.ICF3D, 'X')
            ylabel(app.ICF3D, 'Y')
            zlabel(app.ICF3D, 'Z')
            app.FitstatisticsGaussianpropertiesandgoodnessoffitLabel = uilabel(app.CenterPanel);
            app.FitstatisticsGaussianpropertiesandgoodnessoffitLabel.Text = 'Fit statistics (Gaussian properties and goodness of fit)';
            app.FitmodeDropDownLabel = uilabel(app.CenterPanel);
            app.FitmodeDropDownLabel.Text = 'Fit mode';
            app.FitmodeDropDown = uidropdown(app.CenterPanel);
            app.FitmodeDropDown.Items = {'Symmetrical Gaussian', 'Asymmetrical Gaussian', 'Rotated Elliptical Gaussian'};
            app.FitmodeDropDown.Value = 'Symmetrical Gaussian';
            app.ICSExplorerLabel = uilabel(app.CenterPanel);
            app.ICSExplorerLabel.Text = 'ICS Explorer';
            app.TauSliderLabel = uilabel(app.CenterPanel);
            app.TauSliderLabel.Text = 'Tau';
            app.TauSlider = uislider(app.CenterPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.ICF2D = uiaxes(app.RightPanel);
            title(app.ICF2D, {'2D ICF view and lateral projections'; ''})
            xlabel(app.ICF2D, 'X')
            ylabel(app.ICF2D, 'Y')
            zlabel(app.ICF2D, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ICS_Explorer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end