classdef STICSgui1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        File                          matlab.ui.container.Menu
        LoadDataMenu                  matlab.ui.container.Menu
        ExitProgramMenu               matlab.ui.container.Menu
        ToolsMenu                     matlab.ui.container.Menu
        UpdateDisplayMenu             matlab.ui.container.Menu
        SyncDisplayMenu               matlab.ui.container.Menu
        RestartMenu                   matlab.ui.container.Menu
        SettingsMenu                  matlab.ui.container.Menu
        ProfileMenu                   matlab.ui.container.Menu
        WorkspaceMenu                 matlab.ui.container.Menu
        ThemeMenu                     matlab.ui.container.Menu
        OriginalMenu                  matlab.ui.container.Menu
        LightModeMenu                 matlab.ui.container.Menu
        DarkModeMenu                  matlab.ui.container.Menu
        NightShiftMenu                matlab.ui.container.Menu
        RandomMenu                    matlab.ui.container.Menu
        About                         matlab.ui.container.Menu
        HelpMenu                      matlab.ui.container.Menu
        TutorialMenu                  matlab.ui.container.Menu
        CreditsMenu                   matlab.ui.container.Menu
        LicenseMenu                   matlab.ui.container.Menu
        AboutMenu                     matlab.ui.container.Menu
        TabGroup                      matlab.ui.container.TabGroup
        InstructionsTab               matlab.ui.container.Tab
        instructionsNextButton        matlab.ui.control.Button
        ICSExplorerButton             matlab.ui.control.Button
        DataViewerButton              matlab.ui.control.Button
        JumpLabel                     matlab.ui.control.Label
        STICSButton                   matlab.ui.control.Button
        EachcorrelationspectroscopyalgorithmcontainsLabel  matlab.ui.control.Label
        UsageLabel                    matlab.ui.control.Label
        instructionsInfo              matlab.ui.control.Label
        instructionsTitle             matlab.ui.control.Label
        StartTab                      matlab.ui.container.Tab
        startBackButton               matlab.ui.control.Button
        startNextButton               matlab.ui.control.Button
        LogoImage                     matlab.ui.control.Image
        PapersButton                  matlab.ui.control.Button
        DocsButton                    matlab.ui.control.Button
        NewsButton                    matlab.ui.control.Button
        SavedButton                   matlab.ui.control.Button
        PresetButton                  matlab.ui.control.Button
        LoadsavedworkflowLabel        matlab.ui.control.Label
        LoadpresetworkflowLabel       matlab.ui.control.Label
        NewButton                     matlab.ui.control.StateButton
        CreateanewworkflowLabel       matlab.ui.control.Label
        ChooseaWorkflowLabel          matlab.ui.control.Label
        DataTab                       matlab.ui.container.Tab
        dataMessageLabel              matlab.ui.control.Label
        dataNextButton                matlab.ui.control.Button
        dataBackButton                matlab.ui.control.Button
        dataSwitch                    matlab.ui.control.Switch
        LaunchStackViewerButton       matlab.ui.control.Button
        ClearMetadataButton           matlab.ui.control.Button
        MetadataLabel                 matlab.ui.control.Label
        MetadataUITable               matlab.ui.control.Table
        ClearTableButton              matlab.ui.control.Button
        ClearCurrentButton            matlab.ui.control.Button
        AddtoTableButton              matlab.ui.control.Button
        SelectedFilesLabel            matlab.ui.control.Label
        FileUITable                   matlab.ui.control.Table
        Ch2Lamp                       matlab.ui.control.Lamp
        SelectCh2Button               matlab.ui.control.Button
        Ch1Lamp                       matlab.ui.control.Lamp
        SelectCh1Button               matlab.ui.control.Button
        PrepTab                       matlab.ui.container.Tab
        TabGroup2                     matlab.ui.container.TabGroup
        LoadTab                       matlab.ui.container.Tab
        RegisterTab                   matlab.ui.container.Tab
        BleachTab                     matlab.ui.container.Tab
        CropTab                       matlab.ui.container.Tab
        BackgroundTab                 matlab.ui.container.Tab
        ImmobileTab                   matlab.ui.container.Tab
        FFTTab                        matlab.ui.container.Tab
        SetupTab                      matlab.ui.container.Tab
        RunTab                        matlab.ui.container.Tab
        ResultsTab                    matlab.ui.container.Tab
        AnalysisTab                   matlab.ui.container.Tab
        ExportTab                     matlab.ui.container.Tab
        STICSTab                      matlab.ui.container.Tab
        Switch                        matlab.ui.control.Switch
        movieFormatButtonGroup        matlab.ui.container.ButtonGroup
        jpegButton_2                  matlab.ui.control.RadioButton
        aviButton                     matlab.ui.control.RadioButton
        mp4Button                     matlab.ui.control.RadioButton
        imageFormatButtonGroup        matlab.ui.container.ButtonGroup
        jpegButton                    matlab.ui.control.RadioButton
        pdfButton                     matlab.ui.control.RadioButton
        pngButton                     matlab.ui.control.RadioButton
        outputNameEditField           matlab.ui.control.EditField
        outputNameEditFieldLabel      matlab.ui.control.Label
        rootpathEditField             matlab.ui.control.EditField
        rootpathEditFieldLabel        matlab.ui.control.Label
        axisTitleEditField            matlab.ui.control.EditField
        axisTitleEditFieldLabel       matlab.ui.control.Label
        threshVectorEditField         matlab.ui.control.NumericEditField
        threshVectorEditFieldLabel    matlab.ui.control.Label
        fitRadiusEditField            matlab.ui.control.NumericEditField
        fitRadiusEditFieldLabel       matlab.ui.control.Label
        omegaThresholdEditField       matlab.ui.control.NumericEditField
        omegaThresholdEditFieldLabel  matlab.ui.control.Label
        TOIshiftEditField             matlab.ui.control.NumericEditField
        TOIshiftEditFieldLabel        matlab.ui.control.Label
        TOIsizeEditField              matlab.ui.control.NumericEditField
        TOIsizeEditFieldLabel         matlab.ui.control.Label
        ROIshiftEditField             matlab.ui.control.NumericEditField
        ROIshiftEditFieldLabel        matlab.ui.control.Label
        ROIsizeEditField              matlab.ui.control.NumericEditField
        ROIsizeEditFieldLabel         matlab.ui.control.Label
        tauLimitEditField             matlab.ui.control.NumericEditField
        tauLimitEditFieldLabel        matlab.ui.control.Label
        timeFrameEditField            matlab.ui.control.NumericEditField
        timeFrameEditFieldLabel       matlab.ui.control.Label
        pixelSizeEditField            matlab.ui.control.NumericEditField
        pixelSizeEditFieldLabel       matlab.ui.control.Label
        ErrorLabel                    matlab.ui.control.Label
        MovingAverageEditField        matlab.ui.control.NumericEditField
        butterIIRCheckBox             matlab.ui.control.CheckBox
        MovingAverageCheckBox         matlab.ui.control.CheckBox
        FourierWholeCheckBox          matlab.ui.control.CheckBox
        BrowseRootButton              matlab.ui.control.Button
        SpatioTemporalImageCorrelationSpectroscopySTICSLabel  matlab.ui.control.Label
        RunSTICSButton                matlab.ui.control.Button
        SelectDataSetButton           matlab.ui.control.Button
        exportImagesYNCheckBox        matlab.ui.control.CheckBox
        OutlierCriteriaLabel          matlab.ui.control.Label
        ImmobileRemovalSettingsLabel  matlab.ui.control.Label
    end

    properties (Access = public)
        pixelSize; % Size of pixels
        timeFrame; % Time delay
        tauLimit; % Max # of time lags
        FourierWhole; % Sub. avg img from each img
        MovingAverage; % Sub. average (n-1)/2 frames before & after
        butterIIR; % Immobile pop. removal
        omegaThreshold; % Largest beam radius accepted
        fitRadius; % Radius of circular mask
        threshVector; % Deviation amount
        ROIsize; % Region of interest
        ROIshift; % Space between ROIs
        TOIsize; % Temporal (window) of interest
        TOIshift; % Time between TOIs
        axisTitle; % Title of graph
        outputName; % Output file name
        path; % Stores path to store output
        exportImages; % Export images Y/N
        imageFormat = -1; % Result image format
        movieFormat = -1; % Result movie format
        filePath; % Path of data set file
        croped1; % Cropped image
        decision % Cropping decision


        %dataStack;
        channel1Array;
        channel1PathArray;
        channel2Array;
        channel2PathArray;
        
        Ch1Stack;
        Ch1Path;
        Ch1Name;
        
        Ch2Stack;
        Ch2Path;
        Ch2Name;
        
        currLength;
        
        
        currLine;
    end
    
    properties (Access = private)
        STICSfield_selectionApp; % STICS field selection app
        ICSExplorerApp; % ICS explorer app
        FileViewer; % File previewer app
    end
    
    methods (Access = public)
        % Reset error label to nothing
        function resetErrorLabel(app)
            app.ErrorLabel.Text = '';
            app.ErrorLabel.FontColor = [0.0 0.0 0.0];
        end
        
        % Show file choosen
        function displaySelectedFile(app, fileName)
            app.ErrorLabel.Text = ['File Selected: ' fileName];
            app.ErrorLabel.FontColor = [0.0 0.0 0.0];
        end
        
        % Check if croped1 & path have a value
        function checkRunValid(app)
            if (~isempty(app.croped1) && ~isempty(app.path))
                app.RunSTICSButton.Enable = 'On';
            end
        end
        
        % Retrieve decision from cropping
        function crop_decision(app, decision)
            app.decision = decision;
        end
        
        function resetMetaTable(app)
            app.MetadataUITable.Data = {'FileID', 0; 'Size(x)', 0; 'Size(y)', 0; 'Size(t)', 0;
                'Size(c)', 0; 'PixelSize', 0; 'Frame Rate', 0};
        end
        
        function clearVars(app)
            app.Ch1Name = [];
            app.Ch2Name = [];
            app.Ch1Path = [];
            app.Ch2Path = [];
            
            app.Ch1Lamp.Color = [1.0 0.0 0.0];
            app.Ch2Lamp.Color = [1.0 0.0 0.0];
        end
        
        function changeTheme(app, theme)
            if (strcmp(theme, 'DarkMode'))
                color1Array = [0.0 0.0 0.0];
                color2Array = [1.0 1.0 1.0];
            end
            
            % Instructions Tab
            app.InstructionsTab.BackgroundColor = color1Array;
            app.instructionsTitle.FontColor = color2Array;
            app.instructionsInfo.FontColor = color2Array;
            app.UsageLabel.FontColor = color2Array;
            app.EachcorrelationspectroscopyalgorithmcontainsLabel.FontColor = color2Array;
            app.JumpLabel.FontColor = color2Array;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            resetMetaTable(app);
            app.FileUITable.ColumnWidth = {230, 230, 'auto'};
            app.MetadataUITable.ColumnWidth = {75, 'auto'};
            set(app.FileUITable, 'ColumnEditable', [false, false, true]);
            
            addpath('current/STICS/');
            addpath('IconImages/');
            
            %set(app.LoadDataMenu, 'UserData', 'file_img.png');
            
            %app.FileUITable.Data = {'NAME', 'NAME', false};
            
            %data = get(app.FileUITable, 'Data');
            
            %newRow = {'asdfs', 'asdfsd', false};
            %data(end + 1, :) = newRow;
            
            %set(app.FileUITable, 'Data', data);
            %app.FileUITable.Data = {'asdfs', 'asdfsd', false};
           
            
        end

        % Menu selected function: DarkModeMenu
        function DarkModeMenuSelected(app, event)
           changeTheme(app, 'DarkMode');
        end

        % Callback function
        function instructionsNextButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.StartTab;
        end

        % Callback function
        function startBackButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.InstructionsTab;
        end

        % Callback function
        function startNextButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.DataTab;
        end

        % Button pushed function: SelectCh1Button
        function SelectCh1ButtonPushed(app, event)
            app.Ch1Lamp.Color = [1.0 1.0 0.0];
            
            [fileName, pathName] = uigetfile('*.tif','Please select your data set');
            
            % Check if user clicked cancel
            if (fileName == 0)
                if (isempty(app.Ch1Path))
                    app.Ch1Lamp.Color = [1.0 0.0 0.0];
                else
                    app.Ch1Lamp.Color = [0.0 1.0 0.0];
                end
                
                return
            end   
            
            app.Ch1Path = pathName;
            app.Ch1Name=fileName(1:end-4);
            data = [app.Ch1Path app.Ch1Name '.tif'];
            
            % Check if data is in MultiTiff files
            if (strcmp(fileName(end-2:end),'tif'))
                try
                    app.Ch1Stack = readFileToStack(data);

                catch Error
                    % Select another file
                    [StackFileName, StackPath] = uigetfile('*.tif', 'Select Stack File');
                    app.Ch1Stack = readFileToStack([StackPath, StackFileName]);
                end
                
                isValid = 1;
            else
                isValid = 0;
            end
            
            % Check if file valid
            if (isValid)
                app.Ch1Lamp.Color = [0.0 1.0 0.0];    
            % File invalid
            else
                app.Ch1Lamp.Color = [1.0 0.0 0.0];
            end
        end

        % Button pushed function: SelectCh2Button
        function SelectCh2ButtonPushed(app, event)
            app.Ch2Lamp.Color = [1.0 1.0 0.0];
            
            [fileName, pathName] = uigetfile('*.tif','Please select your data set');
            
            % Check if user clicked cancel
            if (fileName == 0)
                if (isempty(app.Ch1Path))
                    app.Ch1Lamp.Color = [1.0 0.0 0.0];
                else
                    app.Ch1Lamp.Color = [0.0 1.0 0.0];
                end
                
                return
            end   
            
            app.Ch2Path = pathName;
            app.Ch2Name=fileName(1:end-4);
            data = [app.Ch2Path app.Ch1Name '.tif'];
            
            % Check if data is in MultiTiff files
            if (strcmp(fileName(end-2:end),'tif'))
                try
                    app.Ch2Stack = readFileToStack(data);

                catch Error
                    % Select another file
                    [StackFileName, StackPath] = uigetfile('*.tif', 'Select Stack File');
                    app.Ch2Stack = readFileToStack([StackPath, StackFileName]);
                end
                
                isValid = 1;
            else
                isValid = 0;
            end
            
            % Check if file valid
            if (isValid)
                app.Ch2Lamp.Color = [0.0 1.0 0.0];    
            % File invalid
            else
                app.Ch2Lamp.Color = [1.0 0.0 0.0];
            end
        end

        % Button pushed function: AddtoTableButton
        function AddtoTableButtonPushed(app, event)
            % Add row if at least one valid file loaded
            if (~isempty(app.Ch1Name) || ~isempty(app.Ch2Name))
                % Get proper index value in array
                if (isempty(app.currLength))
                    app.currLength = 1;
                else
                    app.currLength = app.currLength + 1;
                end
                
                % Check if Ch1Stack not empty
                if (~isempty(app.Ch1Stack))
                    app.channel1Array(:,:,:,app.currLength) = app.Ch1Stack;
                    app.channel1PathArray(:,:,:,app.currLength) = app.Ch1Path;
                end
                
                % Check if Ch2Stack not empty
                if (~isempty(app.Ch2Stack))
                    app.channel2Array(:,:,:,app.currLength) = app.Ch2Stack;
                    app.channel2PathArray(:,:,:,app.currLength) = app.Ch2Path;
                end
                
                data = get(app.FileUITable, 'Data');
                
                if (isempty(data))
                    app.FileUITable.Data = {app.Ch1Name, app.Ch2Name, false};
                else
                    newRow = {app.Ch1Name, app.Ch2Name, false};
                    data(end + 1, :) = newRow;
                    set(app.FileUITable, 'Data', data);
                end
                
                clearVars(app);
            end
        end

        % Cell edit callback: FileUITable
        function FileUITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            % Check if file line not selected
            if (isempty(app.currLine))
                if (newData)
                    app.currLine = indices(1);
                    
                    data = get(app.FileUITable, 'Data');
                    
                    metaData = get(app.MetadataUITable, 'Data');
                    metaData(1, 2) = data(app.currLine, 1);
                    metaData(2, 2) = {size(app.channel1Array(:,:,:,app.currLine), 2)};
                    metaData(3, 2) = {size(app.channel1Array(:,:,:,app.currLine), 1)};
                    metaData(4, 2) = {size(app.channel1Array(:,:,:,app.currLine), 3)};
                    set(app.MetadataUITable, 'Data', metaData);
                end
            else
                if (newData)
                    data = get(app.FileUITable, 'Data');
                    data(app.currLine, 3) = {false};
                    set(app.FileUITable, 'Data', data);
                
                    app.currLine = indices(1);
                    
                    data = get(app.FileUITable, 'Data');
                    
                    metaData = get(app.MetadataUITable, 'Data');
                    metaData(1, 2) = data(app.currLine, 1);
                    metaData(2, 2) = {size(app.channel1Array(:,:,:,app.currLine), 2)};
                    metaData(3, 2) = {size(app.channel1Array(:,:,:,app.currLine), 1)};
                    metaData(4, 2) = {size(app.channel1Array(:,:,:,app.currLine), 3)};
                    set(app.MetadataUITable, 'Data', metaData);
                else
                    app.currLine = [];
                end
            end
        end

        % Button pushed function: ClearCurrentButton
        function ClearCurrentButtonPushed(app, event)
            clearVars(app);
            
            app.Ch1Stack = [];
            app.Ch2Stack = [];
        end

        % Button pushed function: ClearTableButton
        function ClearTableButtonPushed(app, event)
            set(app.FileUITable, 'Data', {})
            app.currLength = [];
            app.channel1Array = [];
            app.channel1PathArray = [];
            app.channel2Array = [];
            app.channel2PathArray = [];
            app.Ch1Stack = [];
            app.Ch2Stack = [];
        end

        % Value changed function: dataSwitch
        function dataSwitchValueChanged(app, event)
            value = app.dataSwitch.Value;
            
        end

        % Button pushed function: ClearMetadataButton
        function ClearMetadataButtonPushed(app, event)
            resetMetaTable(app);
        end

        % Button pushed function: LaunchStackViewerButton
        function LaunchStackViewerButtonPushed(app, event)
            if (~isempty(app.channel1Array))
                app.Ch1Stack = app.channel1Array(:,:,:,app.currLine);
                app.Ch2Stack = app.channel2Array(:,:,:,app.currLine);
                
                if (~all(app.Ch1Stack))
                    app.Ch1Stack = [];
                elseif (~all(app.Ch2Stack))
                    app.Ch2Stack = [];
                end
            end
            
            app.FileViewer = FilePreviewer(app, app.Ch1Stack, app.Ch2Stack);
        end

        % Button pushed function: dataBackButton
        function dataBackButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.StartTab;
        end

        % Button pushed function: dataNextButton
        function dataNextButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.PrepTab;
        end

        % Value changed function: tauLimitEditField
        function tauLimitEditFieldValueChanged(app, event)
            value = app.tauLimitEditField.Value;
            
            if (value > app.TOIsizeEditField.Value)
                app.ErrorLabel.Text = ['Error: Please select a tauLimit less than or equal to ' num2str(app.TOIsizeEditField.Value)];
                app.ErrorLabel.FontColor = [1.0 0.0 0.0];
            else
                resetErrorLabel(app);
            end
        end

        % Button pushed function: BrowseRootButton
        function BrowseRootButtonPushed(app, event)
            [rootPath] = uigetdir(pwd);
            
            % Check if user clicked cancel
            if (rootPath == 0)
                return
            end
            
            app.path = rootPath;
            
            %fprintf(path);
            app.rootpathEditField.Value = rootPath;
            app.BrowseRootButton.BackgroundColor = [0.33 0.77 0.01];
            
            checkRunValid(app);
        end

        % Value changed function: FourierWholeCheckBox
        function FourierWholeCheckBoxValueChanged(app, event)
            value = app.FourierWholeCheckBox.Value;
            
            if (value)
                app.MovingAverageCheckBox.Enable = 'Off';
                app.butterIIRCheckBox.Enable = 'Off';
            else
                app.MovingAverageCheckBox.Enable = 'On';
                app.butterIIRCheckBox.Enable = 'On';
            end
        end

        % Value changed function: MovingAverageCheckBox
        function MovingAverageCheckBoxValueChanged(app, event)
            value = app.MovingAverageCheckBox.Value;
            
            if (value == 0)
                app.MovingAverageEditField.Enable = 'Off';
            else
                app.MovingAverageEditField.Enable = 'On';
            end
            
            if (value)
                app.FourierWholeCheckBox.Enable = 'Off';
                app.butterIIRCheckBox.Enable = 'Off';
            else
                app.FourierWholeCheckBox.Enable = 'On';
                app.butterIIRCheckBox.Enable = 'On';
            end
        end

        % Value changed function: butterIIRCheckBox
        function butterIIRCheckBoxValueChanged(app, event)
            value = app.butterIIRCheckBox.Value;
            
            if (value)
                app.MovingAverageCheckBox.Enable = 'Off';
                app.FourierWholeCheckBox.Enable = 'Off';
            else
                app.MovingAverageCheckBox.Enable = 'On';
                app.FourierWholeCheckBox.Enable = 'On';
            end
        end

        % Button pushed function: SelectDataSetButton
        function SelectDataSetButtonPushed(app, event)
            [fileName, pathName] = uigetfile('*','Please select your data set');
            
            isValid = 0;
            
            % Check if user clicked cancel
            if (fileName == 0)
                return
            end
            
            app.filePath = pathName;
            ch1name=fileName(1:end-4);
            
            addpath('current/STICS/');
            
            % Check if data is in MultiTiff files
            if (strcmp(fileName(end-2:end),'tif'))
                image_data1=rd_img16([app.filePath ch1name '.tif']);
                app.croped1=image_data1; 
                displaySelectedFile(app, fileName);
                isValid = 1;
            % if data is in microscope formats such as Zeiss .lsm files, 'imreadBF' to
            % load each channel data. Please open the 'imreadBF.m' and see how the
            % input parameters are defined in order to load your data. 
            elseif (strcmp(fileName(end-2:end),'lsm'))
                % Check if loci.tools.jar is in same folder
                if (isfile([app.filePath, '/loci_tools.jar']))
                    app.ErrorLabel.Text = 'Error: File loci_tools.jar is not in the same directory ';
                    app.ErrorLabel.FontColor = [1.0 0.0 0.0];
                end
                
                % loci_tools.jar in same folder as function imreadBFmeta.m
                
                % Check version of MATLAB valid
                v = ver('MATLAB');
                vCheck = v.Version < 7.1;
                if (vCheck(1, 1))
                    app.ErrorLabel.Text = 'Error: This version of MATLAB is not compatible with the algorithm. Please upgrade to Version 7.1+ ';
                    app.ErrorLabel.FontColor = [1.0 0.0 0.0];
                    return;
                end
                
                % MATLAB version valid
                
                %=================================%
                % NOTE: can't find imreadBF.m file
                %=================================%
                
                %image_data1=imreadBF([app.path ch1name '.lsm'],1,1:100,1);
                %croped1=image_data1;
                displaySelectedFile(app, fileName);
            % if data is stored in a .mat variable
            elseif (strcmp(fileName(end-2:end),'mat'))
                image_data1=load([app.filePath ch1name '.mat']);
                app.croped1=image_data1;
                displaySelectedFile(app, fileName);
                isValid = 1;
            else 
                app.ErrorLabel.Text = 'Error: Your input data set should be either ''.tif'' ,''.lsm'' or ''.mat'' ';
                app.ErrorLabel.FontColor = [1.0 0.0 0.0];
                isValid = 0;
            end
            
            if (isValid)
                app.SelectDataSetButton.BackgroundColor = [0.33 0.77 0.01];
            else
                app.SelectDataSetButton.BackgroundColor = [1.0 0.0 0.0];
            end
            
            checkRunValid(app);
        end

        % Button pushed function: RunSTICSButton
        function RunSTICSButtonPushed(app, event)
            % Assign values to defined properties
            app.pixelSize = app.pixelSizeEditField.Value; 
            app.timeFrame = app.timeFrameEditField.Value; 
            app.tauLimit = app.tauLimitEditField.Value; 
            app.FourierWhole = app.FourierWholeCheckBox.Value;
            
            if (app.MovingAverageCheckBox.Value == 1)
                app.MovingAverage = app.MovingAverageEditField.Value;
            else
                app.MovingAverage = 0;
            end

            app.butterIIR = app.butterIIRCheckBox.Value;

            app.omegaThreshold = app.omegaThresholdEditField.Value; 
            app.fitRadius = app.fitRadiusEditField.Value; 
            app.threshVector = app.threshVectorEditField.Value;
            app.ROIsize = app.ROIsizeEditField.Value;
            app.ROIshift = app.ROIshiftEditField.Value;
            app.TOIsize = app.TOIsizeEditField.Value; 
            app.TOIshift = app.TOIshiftEditField.Value; 
            app.axisTitle = app.axisTitleEditField.Value; % str 
            app.outputName = app.outputNameEditField.Value; % str
            app.exportImages = app.exportImagesYNCheckBox.Value; 
            
            % Find which imageFormat button selected
            if (app.pngButton.Value)
                app.imageFormat = 'png';
            elseif (app.pdfButton.Value)
                app.imageFormat = 'pdf';
            else
                app.imageFormat = 'jpeg';
            end
            
            % Find which movieFormat button selected
            if (app.mp4Button.Value)
                app.movieFormat = 'mp4';
            elseif (app.aviButton.Value)
                app.movieFormat = 'avi';
            else
                app.movieFormat = 'jpeg';
            end
            
            app.RunSTICSButton.Enable = 'Off';
            app.STICSfield_selectionApp = STICSfield_selection(app);
            
            imagesc(mean(app.croped1, 3));
            
            if (app.decision == 'Y')
                maskCell=[];
                while (isempty(maskCell))
                    [x,y,maskCell,poly1.x,poly1.y]=roipoly;
                end
            else
                maskCell=ones(size(app.croped1,1),size(app.croped1,2));
            end


            [velocityMap, position_x, position_y, position_t, opt] = stics_vectormapping(app.croped1, app.pixelSize, app.timeFrame, app.tauLimit, app.FourierWhole, app.MovingAverage,... 
                                                                                         app.butterIIR, app.omegaThreshold, app.fitRadius, app.threshVector, app.ROIsize, app.ROIshift,...
                                                                                         app.TOIsize, app.TOIshift, app.axisTitle, app.outputName, app.path, app.exportImages, app.imageFormat,...
                                                                                         app.movieFormat, maskCell);
            
            app.RunSTICSButton.Enable = 'On';
        end

        % Callback function
        function TOIsize_2SliderValueChanged(app, event)
            value = app.TOIsize_2Slider.Value;
            
            % Check if TOIsize < tauLimit_2
            if (value < app.tauLimit_2Slider.Value)
                app.tauLimit_2Slider.Value = value;
            end
        end

        % Callback function
        function tauLimit_2SliderValueChanged(app, event)
            value = app.tauLimit_2Slider.Value;
            
            % Check if tauLiit > TOIsize_2
            if (value > app.TOIsize_2Slider.Value)
                app.tauLimit_2Slider.Value = app.TOIsize_2Slider.Value;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.STICSfield_selectionApp);
            delete(app.ICSExplorerApp);
            delete(app.FileViewer);
            delete(app);
        end

        % Button pushed function: STICSButton
        function STICSButtonPushed(app, event)
            % Go to STICS tab
            app.TabGroup.SelectedTab = app.STICSTab;
        end

        % Button pushed function: ICSExplorerButton
        function ICSExplorerButtonPushed(app, event)
            app.ICSExplorerApp = ICS_Explorer(app);
        end

        % Button pushed function: DataViewerButton
        function DataViewerButtonPushed(app, event)
            app.FileViewer = FilePreviewer(app, [], []);
        end

        % Menu selected function: ProfileMenu
        function ProfileMenuSelected(app, event)
            
        end

        % Menu selected function: ExitProgramMenu
        function ExitProgramMenuSelected(app, event)
            delete(app.STICSfield_selectionApp);
            delete(app.ICSExplorerApp);
            delete(app.FileViewer);
            delete(app);
        end

        % Menu selected function: LoadDataMenu
        function LoadDataMenuSelected(app, event)
            
        end

        % Button pushed function: instructionsNextButton
        function instructionsNextButtonPushed2(app, event)
            app.TabGroup.SelectedTab = app.StartTab;
        end

        % Button pushed function: startBackButton
        function startBackButtonPushed2(app, event)
            app.TabGroup.SelectedTab = app.InstructionsTab;
        end

        % Button pushed function: startNextButton
        function startNextButtonPushed2(app, event)
            app.TabGroup.SelectedTab = app.DataTab;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.File = uimenu(app.UIFigure);
            app.File.Text = 'File';
            app.LoadDataMenu = uimenu(app.File);
            app.LoadDataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadDataMenuSelected, true);
            app.LoadDataMenu.Separator = 'on';
            app.LoadDataMenu.Text = 'Load Data';
            app.ExitProgramMenu = uimenu(app.File);
            app.ExitProgramMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitProgramMenuSelected, true);
            app.ExitProgramMenu.Separator = 'on';
            app.ExitProgramMenu.Text = 'Exit Program';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.UpdateDisplayMenu = uimenu(app.ToolsMenu);
            app.UpdateDisplayMenu.Separator = 'on';
            app.UpdateDisplayMenu.Text = 'Update Display';
            app.SyncDisplayMenu = uimenu(app.ToolsMenu);
            app.SyncDisplayMenu.Separator = 'on';
            app.SyncDisplayMenu.Text = 'Sync Display';
            app.RestartMenu = uimenu(app.ToolsMenu);
            app.RestartMenu.Separator = 'on';
            app.RestartMenu.Text = 'Restart';
            app.SettingsMenu = uimenu(app.UIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.ProfileMenu = uimenu(app.SettingsMenu);
            app.ProfileMenu.MenuSelectedFcn = createCallbackFcn(app, @ProfileMenuSelected, true);
            app.ProfileMenu.Separator = 'on';
            app.ProfileMenu.Text = 'Profile';
            app.WorkspaceMenu = uimenu(app.SettingsMenu);
            app.WorkspaceMenu.Separator = 'on';
            app.WorkspaceMenu.Text = 'Workspace';
            app.ThemeMenu = uimenu(app.SettingsMenu);
            app.ThemeMenu.Separator = 'on';
            app.ThemeMenu.Text = 'Theme';
            app.OriginalMenu = uimenu(app.ThemeMenu);
            app.OriginalMenu.Separator = 'on';
            app.OriginalMenu.Text = 'Original';
            app.LightModeMenu = uimenu(app.ThemeMenu);
            app.LightModeMenu.Separator = 'on';
            app.LightModeMenu.Text = 'Light Mode';
            app.DarkModeMenu = uimenu(app.ThemeMenu);
            app.DarkModeMenu.MenuSelectedFcn = createCallbackFcn(app, @DarkModeMenuSelected, true);
            app.DarkModeMenu.Separator = 'on';
            app.DarkModeMenu.Text = 'Dark Mode';
            app.NightShiftMenu = uimenu(app.ThemeMenu);
            app.NightShiftMenu.Separator = 'on';
            app.NightShiftMenu.Text = 'Night Shift';
            app.RandomMenu = uimenu(app.ThemeMenu);
            app.RandomMenu.Separator = 'on';
            app.RandomMenu.Text = 'Random';
            app.About = uimenu(app.UIFigure);
            app.About.Text = 'About';
            app.HelpMenu = uimenu(app.About);
            app.HelpMenu.Separator = 'on';
            app.HelpMenu.Text = 'Help';
            app.TutorialMenu = uimenu(app.About);
            app.TutorialMenu.Separator = 'on';
            app.TutorialMenu.Text = 'Tutorial';
            app.CreditsMenu = uimenu(app.About);
            app.CreditsMenu.Separator = 'on';
            app.CreditsMenu.Text = 'Credits';
            app.LicenseMenu = uimenu(app.About);
            app.LicenseMenu.Separator = 'on';
            app.LicenseMenu.Text = 'License';
            app.AboutMenu = uimenu(app.About);
            app.AboutMenu.Separator = 'on';
            app.AboutMenu.Text = 'About';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InstructionsTab = uitab(app.TabGroup);
            app.InstructionsTab.Title = 'Instructions';
            app.instructionsTitle = uilabel(app.InstructionsTab);
            app.instructionsTitle.Text = 'Instructions';
            app.instructionsInfo = uilabel(app.InstructionsTab);
            app.instructionsInfo.Text = {'This application implements the Spatio-Temporal Image Correlation Spectroscopy (STICS),  '; 'Spatio-Temporal Image Cross-Correlation Spectroscopy (STICCS), Temporal Image Correlation '; 'Spectroscopy (TICS), and Image Correlation Spectroscopy (ICS) algorithms.'; ''};
            app.UsageLabel = uilabel(app.InstructionsTab);
            app.UsageLabel.Text = 'Usage:';
            app.EachcorrelationspectroscopyalgorithmcontainsLabel = uilabel(app.InstructionsTab);
            app.EachcorrelationspectroscopyalgorithmcontainsLabel.Text = {'- Each correlation spectroscopy algorithms contain pre-set values for all the variables'; '- You can hover over the text/numeric field of each option to see a tooltip explaining how each variable'; 'works in the algorithm'};
            app.STICSButton = uibutton(app.InstructionsTab, 'push');
            app.STICSButton.ButtonPushedFcn = createCallbackFcn(app, @STICSButtonPushed, true);
            app.STICSButton.Text = 'STICS';
            app.JumpLabel = uilabel(app.InstructionsTab);
            app.JumpLabel.Text = 'Quick Jump to Applications';
            app.DataViewerButton = uibutton(app.InstructionsTab, 'push');
            app.DataViewerButton.ButtonPushedFcn = createCallbackFcn(app, @DataViewerButtonPushed, true);
            app.DataViewerButton.Text = 'Data Viewer';
            app.ICSExplorerButton = uibutton(app.InstructionsTab, 'push');
            app.ICSExplorerButton.ButtonPushedFcn = createCallbackFcn(app, @ICSExplorerButtonPushed, true);
            app.ICSExplorerButton.Text = 'ICS Explorer';
            app.instructionsNextButton = uibutton(app.InstructionsTab, 'push');
            app.instructionsNextButton.ButtonPushedFcn = createCallbackFcn(app, @instructionsNextButtonPushed2, true);
            app.instructionsNextButton.Text = 'Next';
            app.StartTab = uitab(app.TabGroup);
            app.StartTab.Title = 'Start';
            app.ChooseaWorkflowLabel = uilabel(app.StartTab);
            app.ChooseaWorkflowLabel.Text = 'Choose a Workflow';
            app.CreateanewworkflowLabel = uilabel(app.StartTab);
            app.CreateanewworkflowLabel.Text = 'Create a new workflow:';
            app.NewButton = uibutton(app.StartTab, 'state');
            app.NewButton.Text = 'New';
            app.LoadpresetworkflowLabel = uilabel(app.StartTab);
            app.LoadpresetworkflowLabel.Text = 'Load preset workflow:';
            app.LoadsavedworkflowLabel = uilabel(app.StartTab);
            app.LoadsavedworkflowLabel.Text = 'Load saved workflow:';
            app.PresetButton = uibutton(app.StartTab, 'push');
            app.PresetButton.Text = 'Preset';
            app.SavedButton = uibutton(app.StartTab, 'push');
            app.SavedButton.Text = 'Saved';
            app.NewsButton = uibutton(app.StartTab, 'push');
            app.NewsButton.Text = 'News';
            app.DocsButton = uibutton(app.StartTab, 'push');
            app.DocsButton.Text = 'Docs';
            app.PapersButton = uibutton(app.StartTab, 'push');
            app.PapersButton.Text = 'Papers';
            app.LogoImage = uiimage(app.StartTab);
            app.LogoImage.ImageSource = 'artist-palette_1f3a8.png';
            app.startNextButton = uibutton(app.StartTab, 'push');
            app.startNextButton.ButtonPushedFcn = createCallbackFcn(app, @startNextButtonPushed2, true);
            app.startNextButton.Text = 'Next';
            app.startBackButton = uibutton(app.StartTab, 'push');
            app.startBackButton.ButtonPushedFcn = createCallbackFcn(app, @startBackButtonPushed2, true);
            app.startBackButton.Text = 'Back';
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.SelectCh1Button = uibutton(app.DataTab, 'push');
            app.SelectCh1Button.ButtonPushedFcn = createCallbackFcn(app, @SelectCh1ButtonPushed, true);
            app.SelectCh1Button.Text = 'Select Ch1';
            app.Ch1Lamp = uilamp(app.DataTab);
            app.SelectCh2Button = uibutton(app.DataTab, 'push');
            app.SelectCh2Button.ButtonPushedFcn = createCallbackFcn(app, @SelectCh2ButtonPushed, true);
            app.SelectCh2Button.Text = 'Select Ch2';
            app.Ch2Lamp = uilamp(app.DataTab);
            app.FileUITable = uitable(app.DataTab);
            app.FileUITable.ColumnName = {'Channel 1'; 'Channel 2'; 'Select File'};
            app.FileUITable.RowName = {};
            app.FileUITable.CellEditCallback = createCallbackFcn(app, @FileUITableCellEdit, true);
            app.SelectedFilesLabel = uilabel(app.DataTab);
            app.SelectedFilesLabel.Text = {'Selected Files'; ''};
            app.AddtoTableButton = uibutton(app.DataTab, 'push');
            app.AddtoTableButton.ButtonPushedFcn = createCallbackFcn(app, @AddtoTableButtonPushed, true);
            app.AddtoTableButton.Text = 'Add to Table';
            app.ClearCurrentButton = uibutton(app.DataTab, 'push');
            app.ClearCurrentButton.ButtonPushedFcn = createCallbackFcn(app, @ClearCurrentButtonPushed, true);
            app.ClearCurrentButton.Text = 'Clear Current';
            app.ClearTableButton = uibutton(app.DataTab, 'push');
            app.ClearTableButton.ButtonPushedFcn = createCallbackFcn(app, @ClearTableButtonPushed, true);
            app.ClearTableButton.Text = 'Clear Table';
            app.MetadataUITable = uitable(app.DataTab);
            app.MetadataUITable.ColumnName = {'Data Type'; 'Value'};
            app.MetadataUITable.RowName = {};
            app.MetadataLabel = uilabel(app.DataTab);
            app.MetadataLabel.Text = 'Metadata';
            app.ClearMetadataButton = uibutton(app.DataTab, 'push');
            app.ClearMetadataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearMetadataButtonPushed, true);
            app.ClearMetadataButton.Text = 'Clear Metadata';
            app.LaunchStackViewerButton = uibutton(app.DataTab, 'push');
            app.LaunchStackViewerButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchStackViewerButtonPushed, true);
            app.LaunchStackViewerButton.Text = 'Launch Stack Viewer';
            app.dataSwitch = uiswitch(app.DataTab, 'slider');
            app.dataSwitch.Items = {'Ch1', 'Ch2'};
            app.dataSwitch.ValueChangedFcn = createCallbackFcn(app, @dataSwitchValueChanged, true);
            app.dataSwitch.Value = 'Ch1';
            app.dataBackButton = uibutton(app.DataTab, 'push');
            app.dataBackButton.ButtonPushedFcn = createCallbackFcn(app, @dataBackButtonPushed, true);
            app.dataBackButton.Text = 'Back';
            app.dataNextButton = uibutton(app.DataTab, 'push');
            app.dataNextButton.ButtonPushedFcn = createCallbackFcn(app, @dataNextButtonPushed, true);
            app.dataNextButton.Text = 'Next';
            app.dataMessageLabel = uilabel(app.DataTab);
            app.dataMessageLabel.Text = '';
            app.PrepTab = uitab(app.TabGroup);
            app.PrepTab.Title = 'Prep';
            app.TabGroup2 = uitabgroup(app.PrepTab);
            app.LoadTab = uitab(app.TabGroup2);
            app.LoadTab.Title = 'Load';
            app.RegisterTab = uitab(app.TabGroup2);
            app.RegisterTab.Title = 'Register';
            app.BleachTab = uitab(app.TabGroup2);
            app.BleachTab.Title = 'Bleach';
            app.CropTab = uitab(app.TabGroup2);
            app.CropTab.Title = 'Crop';
            app.BackgroundTab = uitab(app.TabGroup2);
            app.BackgroundTab.Title = 'Background';
            app.ImmobileTab = uitab(app.TabGroup2);
            app.ImmobileTab.Title = 'Immobile';
            app.FFTTab = uitab(app.TabGroup2);
            app.FFTTab.Title = 'FFT';
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.RunTab = uitab(app.TabGroup);
            app.RunTab.Title = 'Run';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.STICSTab = uitab(app.TabGroup);
            app.STICSTab.Title = 'STICS';
            app.ImmobileRemovalSettingsLabel = uilabel(app.STICSTab);
            app.ImmobileRemovalSettingsLabel.Tooltip = {'Immobile filtering permits the measurement of the flowing population by removing slowly moving or imobile structures from the image series prior to STICS analysis.'};
            app.ImmobileRemovalSettingsLabel.Text = 'Immobile Removal Settings';
            app.OutlierCriteriaLabel = uilabel(app.STICSTab);
            app.OutlierCriteriaLabel.Tooltip = {'Used to minimize the number of spurious vectors included in the results.'};
            app.OutlierCriteriaLabel.Text = 'Outlier Criteria';
            app.exportImagesYNCheckBox = uicheckbox(app.STICSTab);
            app.exportImagesYNCheckBox.Tooltip = {'If this box is checked, then the images of vector maps will be saved in the output directory.'};
            app.exportImagesYNCheckBox.Text = 'exportImages (Y/N)';
            app.SelectDataSetButton = uibutton(app.STICSTab, 'push');
            app.SelectDataSetButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDataSetButtonPushed, true);
            app.SelectDataSetButton.Tooltip = {'This button allows the user to upload a data set to be analysed using the STICS method.'};
            app.SelectDataSetButton.Text = 'Select Data Set';
            app.RunSTICSButton = uibutton(app.STICSTab, 'push');
            app.RunSTICSButton.ButtonPushedFcn = createCallbackFcn(app, @RunSTICSButtonPushed, true);
            app.RunSTICSButton.Enable = 'off';
            app.RunSTICSButton.Tooltip = {'This button allows the user to run a STICS analysis on the file that they uploaded.'};
            app.RunSTICSButton.Text = 'Run STICS';
            app.SpatioTemporalImageCorrelationSpectroscopySTICSLabel = uilabel(app.STICSTab);
            app.SpatioTemporalImageCorrelationSpectroscopySTICSLabel.Text = 'Spatio-Temporal Image Correlation Spectroscopy (STICS)';
            app.BrowseRootButton = uibutton(app.STICSTab, 'push');
            app.BrowseRootButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseRootButtonPushed, true);
            app.BrowseRootButton.Tooltip = {'This button allows you to select the folder where you want to save the results. It will automatically set the working directory.'};
            app.BrowseRootButton.Text = 'Browse Root';
            app.FourierWholeCheckBox = uicheckbox(app.STICSTab);
            app.FourierWholeCheckBox.ValueChangedFcn = createCallbackFcn(app, @FourierWholeCheckBoxValueChanged, true);
            app.FourierWholeCheckBox.Tooltip = {'This removal setting subtracts the average image of the series from each image in the series. It will effectively remove all fluorescent structures that are static for the duration of the image series.'};
            app.FourierWholeCheckBox.Text = 'FourierWhole';
            app.MovingAverageCheckBox = uicheckbox(app.STICSTab);
            app.MovingAverageCheckBox.ValueChangedFcn = createCallbackFcn(app, @MovingAverageCheckBoxValueChanged, true);
            app.MovingAverageCheckBox.Tooltip = {'The average of (n - 1)/2 frames before and (n-1)/2 frames after a given image are subtracted from it. This method of removal works better for large, slowly moving fluorescent entities (e.x large, sliding adhesions).'};
            app.MovingAverageCheckBox.Text = 'MovingAverage';
            app.butterIIRCheckBox = uicheckbox(app.STICSTab);
            app.butterIIRCheckBox.ValueChangedFcn = createCallbackFcn(app, @butterIIRCheckBoxValueChanged, true);
            app.butterIIRCheckBox.Tooltip = {'This removal setting is more appropriate for immobile population removal, independent of the length of the image time series.'};
            app.butterIIRCheckBox.Text = 'butterIIR';
            app.MovingAverageEditField = uieditfield(app.STICSTab, 'numeric');
            app.MovingAverageEditField.Enable = 'off';
            app.MovingAverageEditField.Value = 11;
            app.ErrorLabel = uilabel(app.STICSTab);
            app.ErrorLabel.Text = '';
            app.pixelSizeEditFieldLabel = uilabel(app.STICSTab);
            app.pixelSizeEditFieldLabel.Text = 'pixelSize';
            app.pixelSizeEditField = uieditfield(app.STICSTab, 'numeric');
            app.pixelSizeEditField.Tooltip = {'The size of a pixel in micrometers.'};
            app.pixelSizeEditField.Value = 0.14;
            app.timeFrameEditFieldLabel = uilabel(app.STICSTab);
            app.timeFrameEditFieldLabel.Text = 'timeFrame';
            app.timeFrameEditField = uieditfield(app.STICSTab, 'numeric');
            app.timeFrameEditField.Tooltip = {'The time delay between frames in s (seconds).'};
            app.timeFrameEditField.Value = 10;
            app.tauLimitEditFieldLabel = uilabel(app.STICSTab);
            app.tauLimitEditFieldLabel.Text = 'tauLimit';
            app.tauLimitEditField = uieditfield(app.STICSTab, 'numeric');
            app.tauLimitEditField.ValueChangedFcn = createCallbackFcn(app, @tauLimitEditFieldValueChanged, true);
            app.tauLimitEditField.Tooltip = {'The maximum number of time lags calculated in time correlation analysis. Note: this may end earlier if either the omegaThreshold or correlation local maximum threshold are exceeded. Typically for N frames, the value for tauLimit is ~N/5.'};
            app.tauLimitEditField.Value = 4;
            app.ROIsizeEditFieldLabel = uilabel(app.STICSTab);
            app.ROIsizeEditFieldLabel.Text = 'ROIsize';
            app.ROIsizeEditField = uieditfield(app.STICSTab, 'numeric');
            app.ROIsizeEditField.Tooltip = {'The size of the analysis region of interest (ROI), in pixels, used for velocity mapping analysis. Each arrow on the final velocity map is calculated from a region of pixels (ROIsize) x (ROIsize).'};
            app.ROIsizeEditField.Value = 16;
            app.ROIshiftEditFieldLabel = uilabel(app.STICSTab);
            app.ROIshiftEditFieldLabel.Text = 'ROIshift';
            app.ROIshiftEditField = uieditfield(app.STICSTab, 'numeric');
            app.ROIshiftEditField.Tooltip = {'The space between adjacent analysis regions, in pixels. Note: if ROIshift is set to a value lower than ROIsize, adjacent arrows in the resulting analysis will not contain all unique information.'};
            app.ROIshiftEditField.Value = 4;
            app.TOIsizeEditFieldLabel = uilabel(app.STICSTab);
            app.TOIsizeEditFieldLabel.Text = 'TOIsize';
            app.TOIsizeEditField = uieditfield(app.STICSTab, 'numeric');
            app.TOIsizeEditField.Tooltip = {'The size of the temporal window of interest (TOI), in frames, used to define the overall size of the block of data used to calculate the local correlation function. Note: this is the time equivalent to ROIsize.'};
            app.TOIsizeEditField.Value = 5;
            app.TOIshiftEditFieldLabel = uilabel(app.STICSTab);
            app.TOIshiftEditFieldLabel.Text = 'TOIshift';
            app.TOIshiftEditField = uieditfield(app.STICSTab, 'numeric');
            app.TOIshiftEditField.Tooltip = {'The number of frames by which the TOI is shifted in time. Note: this is the time equivalent of ROIshift. '};
            app.TOIshiftEditField.Value = 1;
            app.omegaThresholdEditFieldLabel = uilabel(app.STICSTab);
            app.omegaThresholdEditFieldLabel.Text = 'omegaThreshold';
            app.omegaThresholdEditField = uieditfield(app.STICSTab, 'numeric');
            app.omegaThresholdEditField.Tooltip = {'This parameter, in pixels, defines the largest beam waist acceptable from the correlation function fit. If the correlation function exceeds this radius for a given analysis subregion, the analysis will terminate for that subregion.'};
            app.omegaThresholdEditField.Value = 50;
            app.fitRadiusEditFieldLabel = uilabel(app.STICSTab);
            app.fitRadiusEditFieldLabel.Text = 'fitRadius';
            app.fitRadiusEditField = uieditfield(app.STICSTab, 'numeric');
            app.fitRadiusEditField.Tooltip = {'This parameter, in pixels, defines the radius of a circular mask centred at the local maximum within ROI, which is considered in the fitting of the 2D Gaussian. Note: for a noisy correlation function, it is preferable to fit the neighbourhood of the local maxima. '};
            app.fitRadiusEditField.Value = 8;
            app.threshVectorEditFieldLabel = uilabel(app.STICSTab);
            app.threshVectorEditFieldLabel.Text = 'threshVector';
            app.threshVectorEditField = uieditfield(app.STICSTab, 'numeric');
            app.threshVectorEditField.Tooltip = {'This threshold marks some vectors as outliiers if they deviate by a certain amount from their neighbours. It is used to assign a ''good'' vector according to its neighborhood.'};
            app.threshVectorEditField.Value = 2;
            app.axisTitleEditFieldLabel = uilabel(app.STICSTab);
            app.axisTitleEditFieldLabel.Text = 'axisTitle';
            app.axisTitleEditField = uieditfield(app.STICSTab, 'text');
            app.axisTitleEditField.Tooltip = {'A string defining the title displayed in the vector map images and movies. '};
            app.axisTitleEditField.Placeholder = '(e.x Vector Map of ____)';
            app.rootpathEditFieldLabel = uilabel(app.STICSTab);
            app.rootpathEditFieldLabel.Text = 'root/path';
            app.rootpathEditField = uieditfield(app.STICSTab, 'text');
            app.rootpathEditField.Editable = 'off';
            app.rootpathEditField.Tooltip = {'A string defining the output directory.'};
            app.rootpathEditField.Placeholder = '(e.x /Users/michael/Desktop/)';
            app.outputNameEditFieldLabel = uilabel(app.STICSTab);
            app.outputNameEditFieldLabel.Text = 'outputName';
            app.outputNameEditField = uieditfield(app.STICSTab, 'text');
            app.outputNameEditField.Tooltip = {'A string used in naming the STICS output files.'};
            app.outputNameEditField.Placeholder = '(e.x Lateral Diffusion of CFTR)';
            app.imageFormatButtonGroup = uibuttongroup(app.STICSTab);
            app.imageFormatButtonGroup.Title = 'imageFormat';
            app.pngButton = uiradiobutton(app.imageFormatButtonGroup);
            app.pngButton.Text = 'png';
            app.pngButton.Value = true;
            app.pdfButton = uiradiobutton(app.imageFormatButtonGroup);
            app.pdfButton.Text = 'pdf';
            app.jpegButton = uiradiobutton(app.imageFormatButtonGroup);
            app.jpegButton.Text = 'jpeg';
            app.movieFormatButtonGroup = uibuttongroup(app.STICSTab);
            app.movieFormatButtonGroup.Title = 'movieFormat';
            app.mp4Button = uiradiobutton(app.movieFormatButtonGroup);
            app.mp4Button.Text = 'mp4';
            app.mp4Button.Value = true;
            app.aviButton = uiradiobutton(app.movieFormatButtonGroup);
            app.aviButton.Text = 'avi';
            app.jpegButton_2 = uiradiobutton(app.movieFormatButtonGroup);
            app.jpegButton_2.Text = 'jpeg';
            app.Switch = uiswitch(app.STICSTab, 'slider');
            app.Switch.Items = {'STICS', 'STICCS'};
            app.Switch.Value = 'STICS';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = STICSgui1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end